classdef myData_process_v2024_8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DatapreprocessTab               matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ArrayvisualizationtfiltersettiingPanel  matlab.ui.container.Panel
        ShowaveragedprintedemptychipwithrefgasButton  matlab.ui.control.Button
        ShowaveragedhighestresponsesituationButton  matlab.ui.control.Button
        ShowaveragedreferencegassituationButton  matlab.ui.control.Button
        framesLabel                     matlab.ui.control.Label
        AverageperEditField             matlab.ui.control.NumericEditField
        AverageperEditFieldLabel        matlab.ui.control.Label
        SettingButton                   matlab.ui.control.Button
        AutoselectpixelPanel            matlab.ui.container.Panel
        ShowautoscaledprintedrawdataButton  matlab.ui.control.Button
        ShowprintedarrayrawarraydataButton  matlab.ui.control.Button
        AutoselectreferenceButton       matlab.ui.control.Button
        AutoselectinkButton             matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        ADCValueforSelectedPixelLabel   matlab.ui.control.Label
        SelectAllImportDataApplyDefaultSettingButton  matlab.ui.control.Button
        PixelChosenPanel                matlab.ui.container.Panel
        CleanallreferenceselectionButton  matlab.ui.control.Button
        CleanallsignalselectionButton   matlab.ui.control.Button
        ShowADCValueButton              matlab.ui.control.Button
        LockButton                      matlab.ui.control.Button
        CancelselectionButton           matlab.ui.control.Button
        SelectReferencePixelsElectrodesButton  matlab.ui.control.Button
        SelectSignalPixelsElectrodesButton  matlab.ui.control.Button
        ResultshownPacketnumberselectionPanel  matlab.ui.container.Panel
        ShowmatrixButton_2              matlab.ui.control.Button
        SelectedtimesEditField          matlab.ui.control.NumericEditField
        SelectedtimesEditFieldLabel     matlab.ui.control.Label
        ShowAutoscaledvalueCheckBox     matlab.ui.control.CheckBox
        sstartdatafromnoVOCsstateneededLabel  matlab.ui.control.Label
        FindHighestResponseSetButton    matlab.ui.control.Button
        maxNumofSetstoreadLabel         matlab.ui.control.Label
        min1Label                       matlab.ui.control.Label
        SelectedsetnumEditField         matlab.ui.control.NumericEditField
        SelectedsetnumLabel             matlab.ui.control.Label
        SetnumtoshowSlider              matlab.ui.control.Slider
        SetnumtoshowLabel               matlab.ui.control.Label
        MatrixselectionDropDown         matlab.ui.control.DropDown
        MatrixselectionDropDownLabel    matlab.ui.control.Label
        ShowmatrixButton                matlab.ui.control.Button
        ColorbarSettingPanel            matlab.ui.container.Panel
        FindsmallsignalinkButton        matlab.ui.control.Button
        AutoselectrangeDropDown         matlab.ui.control.DropDown
        AutoselectrangeLabel            matlab.ui.control.Label
        MINADCvalueEditField            matlab.ui.control.NumericEditField
        MINADCvalueEditFieldLabel       matlab.ui.control.Label
        AutoselectButton                matlab.ui.control.Button
        ManualsettingButton             matlab.ui.control.Button
        MAXADCvalueEditField            matlab.ui.control.NumericEditField
        MAXADCvalueEditFieldLabel       matlab.ui.control.Label
        DataImportPanel                 matlab.ui.container.Panel
        TotalnumofsetsEditField         matlab.ui.control.NumericEditField
        TotalnumofsetsEditFieldLabel    matlab.ui.control.Label
        SelectALLsetsandImportdataButton_2  matlab.ui.control.Button
        EmptychipdataFilefolderSearchandSelectButton  matlab.ui.control.Button
        SelectALLsetsandImportdataButton  matlab.ui.control.Button
        PrintedchipdataFilefolderSearchandSelectButton  matlab.ui.control.Button
        myDatadatfilepathEditField      matlab.ui.control.EditField
        myDatadatfilepathEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        PlotTab                         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        PlottinginonegoButton           matlab.ui.control.Button
        ManuallycorrectSIGbaselineButton  matlab.ui.control.Button
        BaselinecorrectionofREFButton   matlab.ui.control.Button
        baselinesignalEditField         matlab.ui.control.NumericEditField
        baselinerefEditField            matlab.ui.control.NumericEditField
        frame_average                   matlab.ui.control.NumericEditField
        TimefilteringperXframesButton   matlab.ui.control.Button
        ShowSIGNALREFERENCECheckBox_2   matlab.ui.control.CheckBox
        ShowSIGNALREFERENCECheckBox     matlab.ui.control.CheckBox
        ExportsensorselectiondatafileCheckBox  matlab.ui.control.CheckBox
        ExportsensorselectionimageCheckBox  matlab.ui.control.CheckBox
        Notes                           matlab.ui.control.EditField
        EditFieldLabel_2                matlab.ui.control.Label
        DatawillbestoredinFILEFOLDEREditField  matlab.ui.control.EditField
        PLOTrawdataButton_2             matlab.ui.control.Button
        EditFieldLabel                  matlab.ui.control.Label
        SAVEPLOTRAWDATANOTESButton      matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        Plot_autoscaledTab              matlab.ui.container.Tab
        GridLayout2_2                   matlab.ui.container.GridLayout
        PlottinginonegoButton_2         matlab.ui.control.Button
        ManuallycorrectSIGbaselineButton_2  matlab.ui.control.Button
        BaselinecorrectionofREFButton_2  matlab.ui.control.Button
        baselinesignalEditField_2       matlab.ui.control.NumericEditField
        baselinerefEditField_2          matlab.ui.control.NumericEditField
        frame_average_2                 matlab.ui.control.NumericEditField
        TimefilteringperXframesButton_2  matlab.ui.control.Button
        ShowSIGNALREFERENCECheckBox_4   matlab.ui.control.CheckBox
        ShowSIGNALREFERENCECheckBox_3   matlab.ui.control.CheckBox
        ExportsensorselectiondatafileCheckBox_2  matlab.ui.control.CheckBox
        ExportsensorselectionimageCheckBox_2  matlab.ui.control.CheckBox
        Notes_3                         matlab.ui.control.EditField
        EditFieldLabel_4                matlab.ui.control.Label
        PLOTrawdataButton_3             matlab.ui.control.Button
        DatawillbestoredinFILEFOLDEREditField_3  matlab.ui.control.EditField
        DatawillbestoredinFILEFOLDEREditField_3Label  matlab.ui.control.Label
        SAVEPLOTRAWDATANOTESButton_2    matlab.ui.control.Button
        UIAxes3_4                       matlab.ui.control.UIAxes
        CombinedplotsTab                matlab.ui.container.Tab
        removeADCbaselineCheckBox       matlab.ui.control.CheckBox
        removereferencebaselineCheckBox  matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        inYYAXISMODELabel               matlab.ui.control.Label
        PlottfiltereddataButton         matlab.ui.control.Button
        Notes_2                         matlab.ui.control.EditField
        EditFieldLabel_3                matlab.ui.control.Label
        DatawillbestoredinFILEFOLDEREditField_2  matlab.ui.control.EditField
        DatawillbestoredinFILEFOLDERLabel  matlab.ui.control.Label
        SAVEPLOTIMAGEButton             matlab.ui.control.Button
        PLOTrawdataButton               matlab.ui.control.Button
        UIAxes3_2                       matlab.ui.control.UIAxes
        CumulativedistributionTab       matlab.ui.container.Tab
        ShowAutoscaledvalueCheckBox_2   matlab.ui.control.CheckBox
        sstartdatafromnoVOCsstateneededLabel_2  matlab.ui.control.Label
        maxNumofSetstoreadLabel_2       matlab.ui.control.Label
        min1Label_2                     matlab.ui.control.Label
        Notes_4                         matlab.ui.control.EditField
        EditFieldLabel_5                matlab.ui.control.Label
        DatawillbestoredinFILEFOLDEREditField_4  matlab.ui.control.EditField
        DatawillbestoredinFILEFOLDERLabel_2  matlab.ui.control.Label
        SAVEPLOTIMAGEButton_2           matlab.ui.control.Button
        SetnumtoshowSlider_2            matlab.ui.control.Slider
        FindHighestResponseSetButton_2  matlab.ui.control.Button
        MatrixselectionDropDown_2       matlab.ui.control.DropDown
        MatrixselectionDropDown_2Label  matlab.ui.control.Label
        ShowCDFplotButton_2             matlab.ui.control.Button
        SetnumtoshowLabel_2             matlab.ui.control.Label
        ShowCDFplotButton               matlab.ui.control.Button
        SelectedtimesEditField_2        matlab.ui.control.NumericEditField
        SelectedtimesEditField_2Label   matlab.ui.control.Label
        SelectedsetnumEditField_2       matlab.ui.control.NumericEditField
        SelectedsetnumEditField_2Label  matlab.ui.control.Label
        PLOTButton                      matlab.ui.control.Button
        UIAxes3_6                       matlab.ui.control.UIAxes
        UIAxes3_5                       matlab.ui.control.UIAxes
        ElectrodeanalysisTab            matlab.ui.container.Tab
        BothinbasegassituationLabel     matlab.ui.control.Label
        MatrixselectionDropDown_3       matlab.ui.control.DropDown
        MatrixselectionDropDown_2Label_2  matlab.ui.control.Label
        PLOTButton_2                    matlab.ui.control.Button
        UIAxes3_8                       matlab.ui.control.UIAxes
        UIAxes3_7                       matlab.ui.control.UIAxes
        AnalysismanuallyTab             matlab.ui.container.Tab
        fpassEditField                  matlab.ui.control.NumericEditField
        fpassEditFieldLabel             matlab.ui.control.Label
        lowpassfilterButton             matlab.ui.control.Button
        tfilteredonlyButton             matlab.ui.control.Button
        resnormEditField                matlab.ui.control.NumericEditField
        resnormEditFieldLabel           matlab.ui.control.Label
        SaturationvalueCEditField       matlab.ui.control.NumericEditField
        SaturationvalueCEditFieldLabel  matlab.ui.control.Label
        EndtindexEditField              matlab.ui.control.NumericEditField
        EndtindexEditFieldLabel         matlab.ui.control.Label
        StarttindexEditField            matlab.ui.control.NumericEditField
        StarttindexEditFieldLabel       matlab.ui.control.Label
        SmoothingmethodButtonGroup      matlab.ui.container.ButtonGroup
        sgolayButton                    matlab.ui.control.RadioButton
        rloessButton                    matlab.ui.control.RadioButton
        rlowessButton                   matlab.ui.control.RadioButton
        orLabel                         matlab.ui.control.Label
        PickfittingrangeButton          matlab.ui.control.Button
        SmoothedonlyButton              matlab.ui.control.Button
        SmoothingButton                 matlab.ui.control.Button
        FittingdatatypeButtonGroup      matlab.ui.container.ButtonGroup
        t_filtereddatasmoothedButton    matlab.ui.control.RadioButton
        RAWdatasmoothedButton           matlab.ui.control.RadioButton
        t_filtereddataButton            matlab.ui.control.RadioButton
        RAWdataButton                   matlab.ui.control.RadioButton
        cADCAexptEditFieldLabel         matlab.ui.control.Label
        FittingButton                   matlab.ui.control.Button
        EndtvalueEditField              matlab.ui.control.NumericEditField
        EndtvalueEditFieldLabel         matlab.ui.control.Label
        PickendpointButton              matlab.ui.control.Button
        StarttvalueEditField            matlab.ui.control.NumericEditField
        StarttvalueEditFieldLabel       matlab.ui.control.Label
        PickstartpointButton            matlab.ui.control.Button
        AnalyzeTogetherButton           matlab.ui.control.Button
        AnalyzeRawButton                matlab.ui.control.Button
        samplingtimedeviationt0EditField  matlab.ui.control.NumericEditField
        samplingtimedeviationt0EditFieldLabel  matlab.ui.control.Label
        timeconstantEditField           matlab.ui.control.NumericEditField
        timeconstantLabel               matlab.ui.control.Label
        InitialconditionAEditField      matlab.ui.control.NumericEditField
        InitialconditionAEditFieldLabel  matlab.ui.control.Label
        BaselinecorrectionofSIGEditField  matlab.ui.control.NumericEditField
        BaselinecorrectionofSIGEditFieldLabel  matlab.ui.control.Label
        BaselinecorrectionofREFEditField  matlab.ui.control.NumericEditField
        BaselinecorrectionofREFEditFieldLabel  matlab.ui.control.Label
        AnalyzetfilteredButton          matlab.ui.control.Button
        UIAxes3_3                       matlab.ui.control.UIAxes
        AnalysisautomaticallyTab        matlab.ui.container.Tab
        DatacombinationTab              matlab.ui.container.Tab
        DeleteOneTitleFromrightsideButton  matlab.ui.control.Button
        ExtraTitleEditField             matlab.ui.control.EditField
        ExtraTitleEditFieldLabel        matlab.ui.control.Label
        TitleNameEditField              matlab.ui.control.EditField
        TitleNameEditFieldLabel         matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        AddExtraTitleButton             matlab.ui.control.Button
        ChangeTitleButton               matlab.ui.control.Button
        StartfromemptyButton            matlab.ui.control.Button
        AddBottomLineButton             matlab.ui.control.Button
        AddTopLineButton                matlab.ui.control.Button
        AddedValueEditField             matlab.ui.control.NumericEditField
        AddedValueEditFieldLabel        matlab.ui.control.Label
        TurnsideButton                  matlab.ui.control.Button
        DeleteBottomLineButton          matlab.ui.control.Button
        DeleteTopLineButton             matlab.ui.control.Button
        CombinedSaveButton              matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        ExtraDataImportButton           matlab.ui.control.Button
        ProcessedDataImportButton       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        data_read; % whole data got from myData.dat
        data_read_autoscaled;

        data_align = 0; %average of empty array (constant bias capacitance for each pixels without ink)
        data_aligned; %raw measured data - aligned empty array data
        data_aligned_autoscaled;
        
        iftoalign = 0; %signal for if we have aligned data, 0 for no, 1 for yes
        
        num_sets % total number of packets to read
        t_line % time vector(zeroed)

       
        myDatafilepath; %filefolder of myData.dat file of raw measurement data
        myDatafilealignpath; %filefolder of myData.dat file of empty array alignment measurement data

        array_visual_frames=10;


        Select = 0;% callbackfunction selection of patches

        signal = zeros(1,1023); %signal pixel number to anlyze
        reference = zeros(1,1023); % reference signal pixel number to analyze
        signal_lock; %signal pixel number when locked (with non-selected pixels removed in vector)
        reference_lock; %reference pixel number when locked (with non-selected pixels removed in vector)

        patches; %visualization patches

        signal_record;
        reference_record;
        signal_filtered_record;


        signal_record_tfilter;
        reference_record_tfilter;
        signal_filtered_record_tfilter;

        signal_record_tfilter_baselinecorrection;
        reference_record_tfilter_baselinecorrection;
        signal_filtered_record_tfilter_baselinecorrection;

        signal_record_tfilter_baselinecorrection_temp;
        signal_filtered_record_tfilter_baselinecorrection_temp;

        data_lpf;

        data_smoothed;
        data_tf_smoothed;

        reference_baseline;  %empty chip measurement baseline
        deltaADC_baseline;  %printed pixels baseline (with ink, without VOCs)

        analysishandle;
        t_analy_start;
        t_analy_end;


        process_data_path % Description
        added_data % Description
        dir_save % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function DataimportButtonPushed(app, event)


        end

        % Callback function: DatapreprocessTab, ShowmatrixButton
        function ShowmatrixButtonPushed(app, event)

            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end

            %%
            app.MatrixselectionDropDown_2.Value=val;

            
            packet_set=app.SelectedsetnumEditField.Value;
            app.SetnumtoshowSlider.Value=packet_set;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(packet_set);

            %%
            app.SetnumtoshowSlider_2.Value=packet_set;
            app.SelectedtimesEditField_2.Value=t(packet_set);

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,packet_set);
            %d_array=reshape(d_chosed,[32,32]);
            

            %initialization of selected pixel numbers
            app.signal = zeros(1,1023); %signal pixel number to anlyze
            app.reference = zeros(1,1023); % reference signal pixel number to analyze
            app.signal_lock = zeros(1,1023); %signal pixel number to anlyze
            app.reference_lock = zeros(1,1023); % reference signal pixel number to analyze


            %callbackfunction of patch
            function clickpatch(p,i)
                S=app.Select;
                if S==1
                    disp('patch1');
                    set(p,'edgecolor','r','FaceAlpha',0.4);
                    s=app.signal;
                    s(i)=i;
                    app.signal=s;
                    r=app.reference;
                    r(i)=0;
                    app.reference=r;

                    display(s(i));
                    
                elseif S==2
                    disp('patch2');
                    set(p,'edgecolor','k','FaceAlpha',0.4);
                    r=app.reference;
                    r(i)=i;
                    app.reference=r;
                    s=app.signal;
                    s(i)=0;
                    app.signal=s;

                    display(r(i));
                elseif S==0 
                    disp('patch3')
                    set(p,'edgecolor','none','FaceAlpha',1);
                    s=app.signal;
                    r=app.reference;
                    s(i)=0;
                    r(i)=0;
                    app.signal=s;
                    app.reference=r;

                    display(s(i));
                elseif S==3
                    disp(p.CData);
                    app.EditField.Value = p.CData;
                end
            end


            %unit pixel
            X=[0 0.5 1.5 2 1.5 0.5];
            Y=[(0.5)*3^(0.5) 0 0 (0.5)*3^(0.5) 3^(0.5) 3^(0.5)];

            %draw patches

            %1-960,30*32,15*2*32
            for j=1:15
                    for i=1:32
                        p{i+64*(j-1)}=patch(app.UIAxes,X+3*(j-1),Y+(i-1)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
                    for i=33:64
                        p{i+64*(j-1)}=patch(app.UIAxes,X+1.5+3*(j-1),Y+(64.5-i)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
            end
            %961-992,1*32
            for i=1:32
                p{i+960}=patch(app.UIAxes,X+45,Y+(i-1)*3^(0.5),d_chosed(i+960),'cdatamapping','scaled','edgecolor','none');
                %assign buttondown function
                set(p{i+960},'ButtonDownFcn',@(~,~)clickpatch(p{i+960},i+960));
            end  
            %993-1023,1*31
            for i=1:31
                p{i+992}=patch(app.UIAxes,X+46.5,Y+(32.5-i)*3^(0.5),d_chosed(i+992),'cdatamapping','scaled','edgecolor','none');
                set(p{i+992},'ButtonDownFcn',@(~,~)clickpatch(p{i+992},i+992));
            end
    
            app.patches=p;

msgbox('Data Visualization of this Frame/Set is Completed','message')


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: AutoselectButton
        function AutoselectButtonPushed(app, event)
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            val=app.AutoselectrangeDropDown.Value;
             if val == "SensorMatrix"
                array_num=1;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));

             else 
                if val == "All 3 Matrixs"
                    d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));
                end
               
                if val == "SensorMatrix10um5x5"
                array_num=2;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
             end

            d_max=max(max(d));

            app.MINADCvalueEditField.Value=d_min;
            app.MAXADCvalueEditField.Value=d_max;
            %d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));

            %{
            size=app.num_sets;
            x=[1:1:1023];
            y=[1:1:size];
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            %}

           %{
            surf(app.UIAxes,y,x,d1);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d2);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d3);
            hold(app.UIAxes,"off")

            %information=app.UIAxes.Position;
            %display(information);
            %app.UIAxes.Position = [0 0 552 272];
            %inforinner=app.UIAxes.InnerPosition;
            %display(inforinner);
            %app.UIAxes.InnerPosition = [0 0 300 200];
           %}

           %{
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            dd=[d1,d2,d3];
            imagesec(dd);
           %}
           % app.UIAxes.CLim = [d_min d_max];

            colorbar(app.UIAxes,'delete');
            c = colorbar(app.UIAxes); 
            c.Label.String = 'ADC CODE RANGE (/)';
            c.Limits = [d_min d_max];

            if d_max-d_min<=40
                c.Ticks = [d_min: 1 :d_max];
            elseif d_max-d_min <=450
                c.Ticks = [d_min: 10 :d_max];
            else
                c.Ticks = [d_min: 20 :d_max];
            end
            c.FontSize = 12;
            
            colormap(app.UIAxes, "parula"); 
            set(app.UIAxes,'CLim', [d_min d_max]);

            %barp=c.Position;
            c.Location = 'manual';
            c.Position = [0.9423 0.1 0.0113 0.85];
            %display(barp);


            msgbox('The colorbar has been reset','message')

        end

        % Button pushed function: ManualsettingButton
        function ManualsettingButtonPushed(app, event)
            dmin_set=app.MINADCvalueEditField.Value;
            dmax_set=app.MAXADCvalueEditField.Value;
            

            colorbar(app.UIAxes,'delete');
            c = colorbar(app.UIAxes); 
             c.Label.String = 'ADC CODE RANGE (/)';
            c.Limits = [dmin_set dmax_set];
             if dmax_set-dmin_set <=450
                c.Ticks = [dmin_set: 10 :dmax_set];
            else
                c.Ticks = [dmin_set: 20 :dmax_set];
            end
            c.FontSize = 12;
            colormap(app.UIAxes, "parula"); 
            set(app.UIAxes,'CLim', [dmin_set dmax_set]);
            c.Location = 'manual';
            c.Position = [0.9423 0.1 0.0113 0.85];

            msgbox('The colorbar has been reset','message')
        end

        % Button pushed function: SelectSignalPixelsElectrodesButton
        function SelectSignalPixelsElectrodesButtonPushed(app, event)
            app.Select = 1;
            app.SelectSignalPixelsElectrodesButton.BackgroundColor = [0.65 0.65 0.65];
            app.SelectReferencePixelsElectrodesButton.BackgroundColor = [0.96 0.96 0.96];
            app.CancelselectionButton.BackgroundColor = [0.96 0.96 0.96];
            app.ShowADCValueButton.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: SelectReferencePixelsElectrodesButton
        function SelectReferencePixelsElectrodesButtonPushed(app, event)
            app.Select = 2;
            app.SelectSignalPixelsElectrodesButton.BackgroundColor = [0.96 0.96 0.96];
            app.SelectReferencePixelsElectrodesButton.BackgroundColor = [0.65 0.65 0.65];
            app.CancelselectionButton.BackgroundColor = [0.96 0.96 0.96];
            app.ShowADCValueButton.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: CancelselectionButton
        function CancelselectionButtonPushed(app, event)
            app.Select = 0;
            app.SelectSignalPixelsElectrodesButton.BackgroundColor = [0.96 0.96 0.96];
            app.SelectReferencePixelsElectrodesButton.BackgroundColor = [0.96 0.96 0.96];
            app.CancelselectionButton.BackgroundColor = [0.65 0.65 0.65];
            app.ShowADCValueButton.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            if app.LockButton.Text == "Lock"
                app.LockButton.Text = "Unlock";
                app.SelectSignalPixelsElectrodesButton.BackgroundColor = [0.80 0.80 0.80];
                app.SelectReferencePixelsElectrodesButton.BackgroundColor = [0.80 0.80 0.80];
                app.CancelselectionButton.BackgroundColor = [0.80 0.80 0.80];
                app.CleanallsignalselectionButton.BackgroundColor = [0.80 0.80 0.80];
                app.CleanallreferenceselectionButton.BackgroundColor = [0.80 0.80 0.80];
                app.Select=9;
                app.SelectSignalPixelsElectrodesButton.Enable = 'off';
                app.SelectReferencePixelsElectrodesButton.Enable = 'off';
                app.CancelselectionButton.Enable = 'off';
                app.CleanallsignalselectionButton.Enable = 'off';
                app.CleanallreferenceselectionButton.Enable = 'off';


                app.signal_lock=zeros(1,1023);
                app.reference_lock=zeros(1,1023);

                s=app.signal;
                r=app.reference;
                s(find(s==0))=[];
                r(find(r==0))=[];
                app.signal_lock=s;
                app.reference_lock=r;

                disp(s)
                disp(r)


            elseif app.LockButton.Text == "Unlock"
                app.LockButton.Text = "Lock";
                app.SelectSignalPixelsElectrodesButton.BackgroundColor = [0.96 0.96 0.96];
                app.SelectReferencePixelsElectrodesButton.BackgroundColor = [0.96 0.96 0.96];
                app.CancelselectionButton.BackgroundColor = [0.96 0.96 0.96];
                app.CleanallsignalselectionButton.BackgroundColor = [0.96 0.96 0.96];
                app.CleanallreferenceselectionButton.BackgroundColor = [0.96 0.96 0.96];
                app.SelectSignalPixelsElectrodesButton.Enable = 'on';
                app.SelectReferencePixelsElectrodesButton.Enable = 'on';
                app.CancelselectionButton.Enable = 'on';
                app.CleanallsignalselectionButton.Enable = 'on';
                app.CleanallreferenceselectionButton.Enable = 'on';

            end
        end

        % Value changed function: SetnumtoshowSlider
        function SetnumtoshowSliderValueChanged(app, event)
            value = fix(app.SetnumtoshowSlider.Value);
            t=app.t_line;
            app.SelectedsetnumEditField.Value = value;
            app.SelectedtimesEditField.Value = t(value);

            app.SetnumtoshowSlider_2.Value = value;
            app.SelectedsetnumEditField_2.Value = value;
            app.SelectedtimesEditField_2.Value = t(value);

        end

        % Value changed function: SelectedsetnumEditField
        function SelectedsetnumEditFieldValueChanged(app, event)
            value = app.SelectedsetnumEditField.Value;
            app.SetnumtoshowSlider.Value = value;
        end

        % Button pushed function: PLOTrawdataButton_2
        function PLOTrawdataButton_2Pushed(app, event)
            signal_pnum=app.signal_lock;
            ref_pnum=app.reference_lock;
            signal_length=length(signal_pnum);
            ref_length=length(ref_pnum);

            d=app.data_read;

            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            
            d_signal=[];
            for i=1:signal_length
                d_signal=[d_signal;d_chosed(signal_pnum(i),:)];
            end
            d_signal_average=mean(d_signal,1);

            d_ref=[];
            for i=1:ref_length
                d_ref=[d_ref;d_chosed(ref_pnum(i),:)];
            end
            d_ref_average=mean(d_ref,1);

            t=app.t_line;
            
            plot1=app.ShowSIGNALREFERENCECheckBox.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_2.Value;
            
            
            d_filtered=d_signal_average-d_ref_average;
            if plot1==1 && plot2==1
                cla(app.UIAxes3);                
                plot(app.UIAxes3,t,d_signal_average,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3,t,d_filtered,"Color",'b','LineWidth',2);
                legend(app.UIAxes3,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3,'off');


            elseif plot1==1 && plot2==0
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_signal_average,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'signal averaged','reference signal averaged');

            elseif plot2==1 && plot1==0  
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_filtered,"Color",'b','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'Filtered signal');

            end
           

            app.signal_record=d_signal_average;
            app.reference_record=d_ref_average;
            app.signal_filtered_record=d_filtered;


        end

        % Button pushed function: SAVEPLOTRAWDATANOTESButton
        function SAVEPLOTRAWDATANOTESButtonPushed(app, event)
            d_signal_average=app.signal_record;
            d_ref_average=app.reference_record;
            d_filtered=app.signal_filtered_record;

            d_signal_average_tf=app.signal_record_tfilter;
            d_ref_average_tf=app.reference_record_tfilter;
            d_filtered_tf=app.signal_filtered_record_tfilter;

            d_signal_average_tf_bc=app.signal_record_tfilter_baselinecorrection;
            d_ref_average_tf_bc=app.reference_record_tfilter_baselinecorrection;
            d_filtered_tf_bc=app.signal_filtered_record_tfilter_baselinecorrection;


            t=app.t_line;

            d_signal_average=d_signal_average';
            d_ref_average=d_ref_average';
            d_filtered=d_filtered';
            d_signal_average_tf=d_signal_average_tf';
            d_ref_average_tf=d_ref_average_tf';
            d_filtered_tf=d_filtered_tf';
            d_signal_average_tf_bc=d_signal_average_tf_bc';
            d_ref_average_tf_bc=d_ref_average_tf_bc';
            d_filtered_tf_bc=d_filtered_tf_bc';
            t=t';

            data=app.ExportsensorselectiondatafileCheckBox.Value;
            image=app.ExportsensorselectionimageCheckBox.Value;


            datadir = uigetdir();
            app.dir_save=datadir;
            i_foldername=app.DatawillbestoredinFILEFOLDEREditField.Value;
            app.DatawillbestoredinFILEFOLDEREditField_2.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField_3.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField_4.Value=i_foldername;
            notes_content=app.Notes.Value;

            timeframes=app.frame_average.Value;
            baseref=app.reference_baseline;
            basesig=app.deltaADC_baseline;

            cd (datadir);
            if exist(['plotdata',i_foldername],'dir')==0   
                mkdir(['plotdata',i_foldername])
                disp('Folder created.')
                msgbox('Folder created','message')
                 path = ['plotdata',i_foldername];


                 if data==1
                 myData_signal = fullfile(path,'myData_signal.dat');
                 writematrix(d_signal_average,myData_signal,'Delimiter','tab','WriteMode','append');
                 myData_reference = fullfile(path,'myData_reference.dat');
                 writematrix(d_ref_average,myData_reference,'Delimiter','tab','WriteMode','append');
                 myData_filtered = fullfile(path,'myData_filtered.dat');
                 writematrix(d_filtered,myData_filtered,'Delimiter','tab','WriteMode','append');
                 myData_signal_tf = fullfile(path,'myData_signal_timefiltered.dat');
                 writematrix(d_signal_average_tf,myData_signal_tf,'Delimiter','tab','WriteMode','append');
                 myData_reference_tf = fullfile(path,'myData_reference_timefiltered.dat');
                 writematrix(d_ref_average_tf,myData_reference_tf,'Delimiter','tab','WriteMode','append');
                 myData_filtered_tf = fullfile(path,'myData_filtered_timefiltered.dat');
                 writematrix(d_filtered_tf,myData_filtered_tf,'Delimiter','tab','WriteMode','append');
                 myData_signal_tf = fullfile(path,'myData_signal_timefiltered_baselinecorrection.dat');
                 writematrix(d_signal_average_tf_bc,myData_signal_tf,'Delimiter','tab','WriteMode','append');
                 myData_reference_tf = fullfile(path,'myData_reference_timefiltered_baselinecorrection.dat');
                 writematrix(d_ref_average_tf_bc,myData_reference_tf,'Delimiter','tab','WriteMode','append');
                 myData_filtered_tf = fullfile(path,'myData_filtered_timefiltered_baselinecorrection.dat');
                 writematrix(d_filtered_tf_bc,myData_filtered_tf,'Delimiter','tab','WriteMode','append');

                 myData_time = fullfile(path,'myData_time.dat');
                 writematrix(t,myData_time,'Delimiter','tab','WriteMode','append');
                 myData_COMBINED = fullfile(path,'myData_combined.dat');
                 %t; filtered signal; signal; reference
                 combined_data=[t,d_filtered,d_signal_average,d_ref_average,d_filtered_tf,d_signal_average_tf,d_ref_average_tf,d_filtered_tf_bc,d_signal_average_tf_bc,d_ref_average_tf_bc];
                 writematrix(combined_data,myData_COMBINED,'Delimiter','tab','WriteMode','append');


                 disp('Data saved.')
                 msgbox('Data saved','message')
                 end

                 if image==1

                 exportgraphics(app.UIAxes3,['plotdata',i_foldername,'\','selectedPixels.png']) 
                 disp('Image saved.')
                 msgbox('Image saved','message')

                 end

                 settingfilename = fullfile(path,'Notes.txt');
                 f=fopen(settingfilename,'a');
                 tNow = now;
                 fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                 fprintf(f,'Data filatered per: %i frames\r\n',timeframes);
                 fprintf(f,'Reference baseline ADC code value: %s \r\n',baseref);
                 fprintf(f,'Measured delta ADC baseline code value: %s \r\n',basesig);
                 fprintf(f,'Notes: %s \r\n',notes_content);
                 fclose(f);
                 disp('Notes saved.')
                 msgbox('Notes saved','message')

            else
                %fig = uifigure;
                msg = 'Folder alreade exists, data will be added to previous saved data.';
                title = 'Confirm Save';
                selection = uiconfirm(app.UIFigure,msg,title, ...
                    'Options',{'Keep Save','Only Save Notes','Cancel'});
        
                switch selection
                    case 'Keep Save'
                       
                        
                        if data==1
                         path = ['plotdata',i_foldername];
                         myData_signal = fullfile(path,'myData_signal.dat');
                         writematrix(d_signal_average,myData_signal,'Delimiter','tab','WriteMode','append');
                         myData_reference = fullfile(path,'myData_reference.dat');
                         writematrix(d_ref_average,myData_reference,'Delimiter','tab','WriteMode','append');
                         myData_filtered = fullfile(path,'myData_filtered.dat');
                         writematrix(d_filtered,myData_filtered,'Delimiter','tab','WriteMode','append');
                         
                         myData_signal_tf = fullfile(path,'myData_signal_timefiltered.dat');
                         writematrix(d_signal_average_tf,myData_signal_tf,'Delimiter','tab','WriteMode','append');
                         myData_reference_tf = fullfile(path,'myData_reference_timefiltered.dat');
                         writematrix(d_ref_average_tf,myData_reference_tf,'Delimiter','tab','WriteMode','append');
                         myData_filtered_tf = fullfile(path,'myData_filtered_timefiltered.dat');
                         writematrix(d_filtered_tf,myData_filtered_tf,'Delimiter','tab','WriteMode','append');

                        myData_signal_tf = fullfile(path,'myData_signal_timefiltered_baselinecorrection.dat');
                        writematrix(d_signal_average_tf_bc,myData_signal_tf,'Delimiter','tab','WriteMode','append');
                        myData_reference_tf = fullfile(path,'myData_reference_timefiltered_baselinecorrection.dat');
                        writematrix(d_ref_average_tf_bc,myData_reference_tf,'Delimiter','tab','WriteMode','append');
                        myData_filtered_tf = fullfile(path,'myData_filtered_timefiltered_baselinecorrection.dat');
                        writematrix(d_filtered_tf_bc,myData_filtered_tf,'Delimiter','tab','WriteMode','append');

                         myData_time = fullfile(path,'myData_time.dat');
                         writematrix(t,myData_time,'Delimiter','tab','WriteMode','append');
                         myData_COMBINED = fullfile(path,'myData_combined.dat');
                 %t; filtered signal; signal; reference
                        %combined_data=[t,d_filtered,d_signal_average,d_ref_average];
                         %t; filtered signal; signal; reference
                         writematrix(t,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_filtered,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_signal_average,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_ref_average,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_filtered_tf,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_signal_average_tf,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_ref_average_tf,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_filtered_tf_bc,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_signal_average_tf_bc,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_ref_average_tf_bc,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                        end

                        if image==1

                         exportgraphics(app.UIAxes3,['plotdata',i_foldername,'\','selectedPixels.png'])   

                        end


                        settingfilename = fullfile(path,'Notes.txt');
                        f=fopen(settingfilename,'a');
                        tNow = now;
                        fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                        fprintf(f,'Data filatered per: %i frames\r\n',timeframes);
                        fprintf(f,'Reference baseline ADC code value: %s \r\n',baseref);
                        fprintf(f,'Measured delta ADC baseline code value: %s \r\n',basesig);
                        fprintf(f,'Notes: %s \r\n',notes_content);
                        fclose(f);
                        disp('Notes saved.')
                        msgbox('Notes saved','message')

                    case 'Only Save Notes'
                        path = ['plotdata',i_foldername];
                        settingfilename = fullfile(path,'Notes.txt');
                        f=fopen(settingfilename,'a');
                        tNow = now;
                        fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                        fprintf(f,'Data filatered per: %i frames\r\n',timeframes);
                        fprintf(f,'Reference baseline ADC code value: %s \r\n',baseref);
                        fprintf(f,'Measured delta ADC baseline code value: %s \r\n',basesig);
                        fprintf(f,'Notes: %s \r\n',notes_content);
                        fclose(f);
                        disp('Notes saved.')
                        msgbox('Notes saved','message')

                    case 'Cancel'
                            return
                end
                %disp('Warning: Folder already exists.')
                %msgbox('Folder alreade exists, data will be saved in this folder','warning','warn')
            end

           datapath=[datadir,'\',path];
           cd(datapath);
           Data_processed=importdata('myData_combined.dat');
           %Data_processed=Data_processed';
           Data_processed=Data_processed(:,1:4);

           app.UITable.Data=Data_processed;
           cd(datadir);


        end

        % Value changed function: MatrixselectionDropDown
        function MatrixselectionDropDownValueChanged(app, event)
           
            app.signal = zeros(1,1023); 
            app.reference = zeros(1,1023);
        end

        % Button pushed function: 
        % PrintedchipdataFilefolderSearchandSelectButton
        function PrintedchipdataFilefolderSearchandSelectButtonPushed(app, event)
                maindir = uigetdir();
                app.myDatadatfilepathEditField.Value = maindir;
                app.myDatafilepath = maindir;
        end

        % Button pushed function: SelectALLsetsandImportdataButton
        function SelectALLsetsandImportdataButtonPushed(app, event)
            myDatapath = get(app.myDatadatfilepathEditField,'value');
            
            %%display(myDatapath);
            %%display(NumofSet);
            str2 = "\myData.dat";
            str3 = "\time.dat";
            dat=append(myDatapath,str2);
            tim=append(myDatapath,str3);
            %%display(str);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Importdata  

            %%READ THE FIRST PACKET (DATE NUMBER INCLUDED)
            
            d=load(dat);
            B=load(tim);
            B=datevec(B);
            B=B';
            N=width(d);
            b=length(B);
            B=B(:,b-N+1:b);
            B(1:3,:)=[];
            t_insec=B(1,:)*60*60+B(2,:)*60+B(3,:);
            t_zero=t_insec-t_insec(1);
            
            app.TotalnumofsetsEditField.Value=N;
                        

            %auto-scale
            dlength=length(d(1,:));  %whole package number
            d_average=mean([d(:,1),d(:,2),d(:,3),d(:,4),d(:,5)],2);   %average for every data

            for i=1:dlength
                d_autoscaled(:,i)=d(:,i)-d_average;
            end

            %%display(t_zero);
            app.data_read=d;
            app.data_read_autoscaled=d_autoscaled;
            app.num_sets=length(t_zero);
            app.t_line=t_zero;


            %%slider
            app.SetnumtoshowSlider.Limits=[1 N];
            app.SetnumtoshowSlider.MajorTicks=[1:1:N];
            app.SetnumtoshowSlider.MinorTicks=[1:1:N];  

            app.SetnumtoshowSlider_2.Limits=[1 N];
            app.SetnumtoshowSlider_2.MajorTicks=[1:1:N];
            app.SetnumtoshowSlider_2.MinorTicks=[1:1:N];


            %check if we already have empty align data
            d_align=app.data_align;
            dalign_length=length(d_align);
            if dalign_length >1
                d_aligned=d-d_align;

                %auto-scale
                dlength=length(d_aligned(1,:));  %whole package number
                d_average=mean([d_aligned(:,1),d_aligned(:,2),d_aligned(:,3),d_aligned(:,4),d_aligned(:,5)],2);   %average for every data

                for i=1:dlength
                    d_aligned_autoscaled(:,i)=d_aligned(:,i)-d_average;
                end

            app.data_aligned=d_aligned;
            app.data_aligned_autoscaled=d_aligned_autoscaled;


             app.ShowprintedarrayrawarraydataButton.BackgroundColor = [0.96 0.96 0.96];
             app.ShowautoscaledprintedrawdataButton.BackgroundColor = [0.96 0.96 0.96];
             app.AutoselectinkButton.BackgroundColor = [0.96 0.96 0.96];
             app.AutoselectreferenceButton.BackgroundColor = [0.96 0.96 0.96];

             app.ShowprintedarrayrawarraydataButton.Enable = 'on';
             app.ShowautoscaledprintedrawdataButton.Enable = 'on';
             app.AutoselectinkButton.Enable = 'on';
             app.AutoselectreferenceButton.Enable = 'on';

            end


        end

        % Button pushed function: FindHighestResponseSetButton
        function FindHighestResponseSetButtonPushed(app, event)
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
                
            %%%%
            app.MatrixselectionDropDown_2.Value=val;

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            %d_array=reshape(d_chosed,[32,32]);
            
            [max_value, position_max]=max(d_chosed(:));
            [x,y]=ind2sub(size(d_chosed),position_max);
            app.SelectedsetnumEditField.Value=y;
            app.SetnumtoshowSlider.Value=y;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(y);

            %%
            app.SelectedsetnumEditField_2.Value=y;
            app.SetnumtoshowSlider_2.Value=y;
            app.SelectedtimesEditField_2.Value=t(y);


        end

        % Button pushed function: ProcessedDataImportButton
        function ProcessedDataImportButtonPushed(app, event)
            datapath=uigetdir();
            app.process_data_path=datapath;

            cd(datapath);
            Data_processed=importdata('myData_combined.dat');
            %Data_processed=Data_processed';
            Data_processed=Data_processed(:,1:4);

            app.UITable.Data=Data_processed;
           

        end

        % Button pushed function: TurnsideButton
        function TurnsideButtonPushed(app, event)
            AD=app.added_data;
            AD=AD';
            app.added_data=AD;
            app.UITable_2.Data=AD;
        end

        % Button pushed function: StartfromemptyButton
        function StartfromemptyButtonPushed(app, event)
            AD=[];
            app.added_data=AD;
            app.UITable_2.Data=AD;
        end

        % Button pushed function: DeleteTopLineButton
        function DeleteTopLineButtonPushed(app, event)
            AD=app.added_data;
            AD(1)=[];
            app.added_data=AD;
            app.UITable_2.Data=AD;
        end

        % Button pushed function: DeleteBottomLineButton
        function DeleteBottomLineButtonPushed(app, event)
            AD=app.added_data;
            l=length(AD);
            if l>0
                AD(l)=[];
                app.added_data=AD;
                app.UITable_2.Data=AD;
            end
        end

        % Button pushed function: AddTopLineButton
        function AddTopLineButtonPushed(app, event)
             AD=app.added_data;
             ad=app.AddedValueEditField;
             AD=[ad;AD];
             app.added_data=AD;
             app.UITable_2.Data=AD;
        end

        % Button pushed function: AddBottomLineButton
        function AddBottomLineButtonPushed(app, event)
             AD=app.added_data;
             ad=app.AddedValueEditField;
             AD=[AD;ad];
             app.added_data=AD;
             app.UITable_2.Data=AD;
        end

        % Button pushed function: ChangeTitleButton
        function ChangeTitleButtonPushed(app, event)
            TN=app.TitleNameEditField;
            app.UITable_2.ColumnName='TN';
        end

        % Button pushed function: PLOTrawdataButton
        function PLOTrawdataButtonPushed(app, event)
            t=app.t_line;
           
            baseref=app.reference_baseline;
            basesig=app.deltaADC_baseline;

            plot1=app.removereferencebaselineCheckBox.Value;
            plot2=app.removeADCbaselineCheckBox.Value;

            if plot1==0 && plot2==0
                d_signal_average=app.signal_record;
                d_ref_average=app.reference_record;
                d_filtered=app.signal_filtered_record;

                cla(app.UIAxes3_2);
                yyaxis (app.UIAxes3_2,'left')
                ylabel(app.UIAxes3_2,'raw data ADC code(/)'); 
                app.UIAxes3_2.YColor ='k';
                plot(app.UIAxes3_2,t,d_signal_average,'-',"Color",'r','LineWidth',2);
                hold(app.UIAxes3_2,'on');
                plot(app.UIAxes3_2,t,d_ref_average,'-',"Color",'k','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                yyaxis (app.UIAxes3_2,'right')
                ylabel(app.UIAxes3_2,'filtered data ADC code(/)'); 
                app.UIAxes3_2.YColor ='b';
                plot(app.UIAxes3_2,t,d_filtered,'-',"Color",'b','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                legend(app.UIAxes3_2,'Signal averaged','Reference signal averaged', 'Filtered signal');

            elseif plot1==1 && plot2==0
                d_signal_average=app.signal_record;
                d_ref_average=app.reference_record;

                d_ref_average=d_ref_average-baseref;
                d_signal_average=d_signal_average-baseref;
                d_filtered=d_signal_average-d_ref_average;

                cla(app.UIAxes3_2);
                yyaxis (app.UIAxes3_2,'left')
                ylabel(app.UIAxes3_2,'raw data ADC code(/)'); 
                app.UIAxes3_2.YColor ='k';
                plot(app.UIAxes3_2,t,d_signal_average,'-',"Color",'r','LineWidth',2);
                hold(app.UIAxes3_2,'on');
                plot(app.UIAxes3_2,t,d_ref_average,'-',"Color",'k','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                yyaxis (app.UIAxes3_2,'right')
                ylabel(app.UIAxes3_2,'filtered data ADC code(/)'); 
                app.UIAxes3_2.YColor ='b';
                plot(app.UIAxes3_2,t,d_filtered,'-',"Color",'b','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                legend(app.UIAxes3_2,'Signal averaged','Reference signal averaged', 'Filtered signal');

            elseif plot1==0 && plot2==1
                d_signal_average=app.signal_record;
                d_ref_average=app.reference_record;
                d_filtered=app.signal_filtered_record;

                d_signal_average=d_signal_average-basesig;
                d_filtered=d_filtered-basesig;
                cla(app.UIAxes3_2);
                yyaxis (app.UIAxes3_2,'left')
                ylabel(app.UIAxes3_2,'raw data ADC code(/)'); 
                app.UIAxes3_2.YColor ='k';
                plot(app.UIAxes3_2,t,d_signal_average,'-',"Color",'r','LineWidth',2);
                hold(app.UIAxes3_2,'on');
                plot(app.UIAxes3_2,t,d_ref_average,'-',"Color",'k','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                yyaxis (app.UIAxes3_2,'right')
                ylabel(app.UIAxes3_2,'filtered data ADC code(/)'); 
                app.UIAxes3_2.YColor ='b';
                plot(app.UIAxes3_2,t,d_filtered,'-',"Color",'b','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                legend(app.UIAxes3_2,'Signal averaged','Reference signal averaged', 'Filtered signal');
            else

                d_signal_average=app.signal_record;
                d_ref_average=app.reference_record;
               
              
                d_ref_average=d_ref_average-baseref;
                d_signal_average=d_signal_average-baseref-basesig;
                d_filtered=d_signal_average-d_ref_average;
                 cla(app.UIAxes3_2);
                yyaxis (app.UIAxes3_2,'left')
                ylabel(app.UIAxes3_2,'raw data ADC code(/)'); 
                app.UIAxes3_2.YColor ='k';
                plot(app.UIAxes3_2,t,d_signal_average,'-',"Color",'r','LineWidth',2);
                hold(app.UIAxes3_2,'on');
                plot(app.UIAxes3_2,t,d_ref_average,'-',"Color",'k','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                yyaxis (app.UIAxes3_2,'right')
                ylabel(app.UIAxes3_2,'filtered data ADC code(/)'); 
                app.UIAxes3_2.YColor ='b';
                plot(app.UIAxes3_2,t,d_filtered,'-',"Color",'b','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                legend(app.UIAxes3_2,'Signal averaged','Reference signal averaged', 'Filtered signal');
            end


        end

        % Button pushed function: SAVEPLOTIMAGEButton
        function SAVEPLOTIMAGEButtonPushed(app, event)

            datadir=app.dir_save;


            i_foldername=app.DatawillbestoredinFILEFOLDEREditField.Value;
            app.DatawillbestoredinFILEFOLDEREditField_2.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField_3.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField_4.Value=i_foldername;
            notes_content=app.Notes_2.Value;

            cd (datadir);
            if exist(['plotdata',i_foldername],'dir')==0   
                mkdir(['plotdata',i_foldername])
                disp('Folder created.')
                msgbox('Folder created','message')
                 path = ['plotdata',i_foldername];

                 exportgraphics(app.UIAxes3_2,['plotdata',i_foldername,'\','selectedPixels_yyaxis.png'])   
                 disp('Image saved.')
                 msgbox('Image saved','message')

 
                 settingfilename = fullfile(path,'Notes.txt');
                 f=fopen(settingfilename,'a');
                 tNow = now;
                 fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                 fprintf(f,'Notes: %s \r\n',notes_content);
                 fclose(f);
                 disp('Notes saved.')
                 msgbox('Notes saved','message')

            else
                %fig = uifigure;
                msg = 'Folder alreade exists, yyaxis plot will be added to this folder and replace the previous one.';
                title = 'Confirm Save';
                selection = uiconfirm(app.UIFigure,msg,title, ...
                    'Options',{'Keep Save','Only Save Notes','Cancel'});
        
                switch selection
                    case 'Keep Save'
                       

                         path = ['plotdata',i_foldername];

                         exportgraphics(app.UIAxes3_2,['plotdata',i_foldername,'\','selectedPixels_yyaxis.png'])   
                         disp('Image saved.')
                         msgbox('Image saved','message')


                        settingfilename = fullfile(path,'Notes.txt');
                        f=fopen(settingfilename,'a');
                        tNow = now;
                        fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                        fprintf(f,'Notes: %s \r\n',notes_content);
                        fclose(f);
                        disp('Notes saved.')
                        msgbox('Notes saved','message')

                    case 'Only Save Notes'
                        path = ['plotdata',i_foldername];
                        settingfilename = fullfile(path,'Notes.txt');
                        f=fopen(settingfilename,'a');
                        tNow = now;
                        fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                        fprintf(f,'Notes: %s \r\n',notes_content);
                        fclose(f);
                        disp('Notes saved.')
                        msgbox('Notes saved','message')

                    case 'Cancel'
                            return
                end
                %disp('Warning: Folder already exists.')
                %msgbox('Folder alreade exists, data will be saved in this folder','warning','warn')
            end


        end

        % Button pushed function: 
        % SelectAllImportDataApplyDefaultSettingButton
        function SelectAllImportDataApplyDefaultSettingButtonPushed(app, event)
            
            %import emtpy array data for alignment
            if app.iftoalign == 1
                myDatapath = app.myDatafilealignpath;
            
                %%display(myDatapath);
                %%display(NumofSet);
                str2 = "\myData.dat";
                str=append(myDatapath,str2);
                %%display(str);

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Importdata  

                %%READ THE FIRST PACKET (DATE NUMBER INCLUDED)
            
                fid = fopen(str,'r'); 
                t1=fgets(fid);
                tt1=textscan(t1,'%f %s %f :%f :%f');
                %dump=fgets(fid);
                data=textscan(fid,'%f', 'Delimiter', ' ', 'CollectOutput', 1);
                d1=data{1};
                % d1(1)  packet number 
                % d1(2)  1st array
                % d1(3-1026)  data of 1st array   %n+2
                % d1(1027)  1st array
                % d1(1028-2051)  data of 1st array  %n+1027
                % d1(2052)  1st array
                % d1(2053-3076)  data of 1st array  %n+2051
                % d1(3077)  dump data/date information
                d=d1;

                N=1;
                Length=3077;
                %%READ THE FOLLOWING PACKETS (DATE NUMBER LOST)
                while Length==3077
                t_next=fgets(fid);
                tt_next=textscan(t_next,'%s %f :%f :%f');%lost 1 date number due to previous textscan
                %dump=fgets(fid);
                data=textscan(fid,'%f', 'Delimiter', ' ', 'CollectOutput', 1);
                d_next=data{1};
                Length=length(d_next);

                N=N+1;
    
                    if Length==3077
                        d=[d,d_next];
                    end
                end

                d(3077,:)=[];
                d=[d,d_next];

            
                %N=total sets

                %sum each pixel among all time
                d_sum=sum(d,2);
                %average
                d_average=d_sum/N;


                app.data_align=d_average;
            end
            

            %%%import data
            myDatapath = app.myDatafilepath;
            
            %%display(myDatapath);
            %%display(NumofSet);
            str2 = "\myData.dat";
            str3 = "\time.dat";
            dat=append(myDatapath,str2);
            tim=append(myDatapath,str3);
            %%display(str);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Importdata  

            %%READ THE FIRST PACKET (DATE NUMBER INCLUDED)
            
            d=load(dat);
            B=load(tim);
            B=datevec(B);
            B=B';
            N=width(d);
            b=length(B);
            B=B(:,b-N+1:b);
            B(1:3,:)=[];
            t_insec=B(1,:)*60*60+B(2,:)*60+B(3,:);
            t_zero=t_insec-t_insec(1);
            
            app.TotalnumofsetsEditField.Value=N;

            %auto-scale
            dlength=length(d(1,:));  %whole package number
            d_average=mean([d(:,1),d(:,2),d(:,3),d(:,4),d(:,5)],2);   %average for every data

            for i=1:dlength
                d_autoscaled(:,i)=d(:,i)-d_average;
            end

            %%display(t_zero);
            app.data_read=d;
            app.data_read_autoscaled=d_autoscaled;
            app.num_sets=length(t_zero);
            app.t_line=t_zero;


            %%slider
            app.SetnumtoshowSlider.Limits=[1 N];
            app.SetnumtoshowSlider.MajorTicks=[1:1:N];
            app.SetnumtoshowSlider.MinorTicks=[1:1:N];  

            app.SetnumtoshowSlider_2.Limits=[1 N];
            app.SetnumtoshowSlider_2.MajorTicks=[1:1:N];
            app.SetnumtoshowSlider_2.MinorTicks=[1:1:N];


            %check if we already have empty align data
            d_align=app.data_align;
            dalign_length=length(d_align);
            if dalign_length >1 
                d_aligned=d-d_align;

                %auto-scale
                dlength=length(d_aligned(1,:));  %whole package number
                d_average=mean([d_aligned(:,1),d_aligned(:,2),d_aligned(:,3),d_aligned(:,4),d_aligned(:,5)],2);   %average for every data

                for i=1:dlength
                    d_aligned_autoscaled(:,i)=d_aligned(:,i)-d_average;
                end

            app.data_aligned=d_aligned;
            app.data_aligned_autoscaled=d_aligned_autoscaled;


             app.ShowprintedarrayrawarraydataButton.BackgroundColor = [0.96 0.96 0.96];
             app.ShowautoscaledprintedrawdataButton.BackgroundColor = [0.96 0.96 0.96];
             app.AutoselectinkButton.BackgroundColor = [0.96 0.96 0.96];
             app.AutoselectreferenceButton.BackgroundColor = [0.96 0.96 0.96];

             app.ShowprintedarrayrawarraydataButton.Enable = 'on';
             app.ShowautoscaledprintedrawdataButton.Enable = 'on';
             app.AutoselectinkButton.Enable = 'on';
             app.AutoselectreferenceButton.Enable = 'on';

            else
             app.ShowprintedarrayrawarraydataButton.BackgroundColor = [0.8 0.8 0.8];
             app.ShowautoscaledprintedrawdataButton.BackgroundColor = [0.8 0.8 0.8];
             app.AutoselectinkButton.BackgroundColor = [0.8 0.8 0.8];
             app.AutoselectreferenceButton.BackgroundColor = [0.8 0.8 0.8];

             app.ShowprintedarrayrawarraydataButton.Enable = 'off';
             app.ShowautoscaledprintedrawdataButton.Enable = 'off';
             app.AutoselectinkButton.Enable = 'off';
             app.AutoselectreferenceButton.Enable = 'off';
            
            
            end


            %%%Set ADC range
            %All 3 matrix in default mode
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            val=app.AutoselectrangeDropDown.Value;
             if val == "SensorMatrix"
                array_num=1;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));

             else 
                if val == "All 3 Matrixs"
                    d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));
                end
               
                if val == "SensorMatrix10um5x5"
                array_num=2;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
             end

            d_max=max(max(d));

            app.MINADCvalueEditField.Value=d_min;
            app.MAXADCvalueEditField.Value=d_max;
            %d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));

            %{
            size=app.num_sets;
            x=[1:1:1023];
            y=[1:1:size];
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            %}

           %{
            surf(app.UIAxes,y,x,d1);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d2);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d3);
            hold(app.UIAxes,"off")

            %information=app.UIAxes.Position;
            %display(information);
            %app.UIAxes.Position = [0 0 552 272];
            %inforinner=app.UIAxes.InnerPosition;
            %display(inforinner);
            %app.UIAxes.InnerPosition = [0 0 300 200];
           %}

           %{
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            dd=[d1,d2,d3];
            imagesec(dd);
           %}
           % app.UIAxes.CLim = [d_min d_max];

            colorbar(app.UIAxes,'delete');
            c = colorbar(app.UIAxes); 
            c.Label.String = 'ADC CODE RANGE (/)';
            c.Limits = [d_min d_max];

            if d_max-d_min<=40
                c.Ticks = [d_min: 1 :d_max];
            elseif d_max-d_min <=450
                c.Ticks = [d_min: 10 :d_max];
            else
                c.Ticks = [d_min: 20 :d_max];
            end
            c.FontSize = 12;
            
            colormap(app.UIAxes, "parula"); 
            set(app.UIAxes,'CLim', [d_min d_max]);

            %barp=c.Position;
            c.Location = 'manual';
            c.Position = [0.9423 0.1 0.0113 0.85];
            %display(barp);


            msgbox('The colorbar has been reset','message')


            %%%Find Highest response set num
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
                
            %%%%
            app.MatrixselectionDropDown_2.Value=val;

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            %d_array=reshape(d_chosed,[32,32]);
            
            [max_value, position_max]=max(d_chosed(:));
            [x,y]=ind2sub(size(d_chosed),position_max);
            app.SelectedsetnumEditField.Value=y;
            app.SetnumtoshowSlider.Value=y;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(y);

            %%
            app.SelectedsetnumEditField_2.Value=y;
            app.SetnumtoshowSlider_2.Value=y;
            app.SelectedtimesEditField_2.Value=t(y);

            %%%create patch/show patch
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            packet_set=app.SelectedsetnumEditField.Value;

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end 
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,packet_set);
            %d_array=reshape(d_chosed,[32,32]);


            %initialization of selected pixel numbers
            app.signal = zeros(1,1023); %signal pixel number to anlyze
            app.reference = zeros(1,1023); % reference signal pixel number to analyze
            app.signal_lock = zeros(1,1023); %signal pixel number to anlyze
            app.reference_lock = zeros(1,1023); % reference signal pixel number to analyze

            %callbackfunction of patch
            function clickpatch(p,i)
                S=app.Select;
                if S==1
                    disp('patch1');
                    set(p,'edgecolor','r','FaceAlpha',0.4);
                    s=app.signal;
                    s(i)=i;
                    app.signal=s;
                    r=app.reference;
                    r(i)=0;
                    app.reference=r;

                    display(s(i));
                    
                elseif S==2
                    disp('patch2');
                    set(p,'edgecolor','k','FaceAlpha',0.4);
                    r=app.reference;
                    r(i)=i;
                    app.reference=r;
                    s=app.signal;
                    s(i)=0;
                    app.signal=s;

                    display(r(i));
                elseif S==0 
                    disp('patch3')
                    set(p,'edgecolor','none','FaceAlpha',1);
                    s=app.signal;
                    r=app.reference;
                    s(i)=0;
                    r(i)=0;
                    app.signal=s;
                    app.reference=r;

                    display(s(i));
                elseif S==3
                    disp(p.CData);
                    app.EditField.Value = p.CData;
                end
            end


            %unit pixel
            X=[0 0.5 1.5 2 1.5 0.5];
            Y=[(0.5)*3^(0.5) 0 0 (0.5)*3^(0.5) 3^(0.5) 3^(0.5)];

            %draw patches

            %1-960,30*32,15*2*32
            for j=1:15
                    for i=1:32
                        p{i+64*(j-1)}=patch(app.UIAxes,X+3*(j-1),Y+(i-1)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
                    for i=33:64
                        p{i+64*(j-1)}=patch(app.UIAxes,X+1.5+3*(j-1),Y+(64.5-i)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
            end
            %961-992,1*32
            for i=1:32
                p{i+960}=patch(app.UIAxes,X+45,Y+(i-1)*3^(0.5),d_chosed(i+960),'cdatamapping','scaled','edgecolor','none');
                %assign buttondown function
                set(p{i+960},'ButtonDownFcn',@(~,~)clickpatch(p{i+960},i+960));
            end  
            %993-1023,1*31
            for i=1:31
                p{i+992}=patch(app.UIAxes,X+46.5,Y+(32.5-i)*3^(0.5),d_chosed(i+992),'cdatamapping','scaled','edgecolor','none');
                set(p{i+992},'ButtonDownFcn',@(~,~)clickpatch(p{i+992},i+992));
            end

            app.patches=p;


msgbox('Data Visualization of this Frame/Set is Completed','message')


        end

        % Button pushed function: ShowADCValueButton
        function ShowADCValueButtonPushed(app, event)
            app.Select=3;
            app.SelectSignalPixelsElectrodesButton.BackgroundColor = [0.96 0.96 0.96];
            app.SelectReferencePixelsElectrodesButton.BackgroundColor = [0.96 0.96 0.96];
            app.CancelselectionButton.BackgroundColor = [0.96 0.96 0.96];
            app.ShowADCValueButton.BackgroundColor = [0.65 0.65 0.65];
        end

        % Button pushed function: TimefilteringperXframesButton
        function TimefilteringperXframesButtonPushed(app, event)
            
            signal_pnum=app.signal_lock;
            ref_pnum=app.reference_lock;
            signal_length=length(signal_pnum);
            ref_length=length(ref_pnum);

            d=app.data_read;

            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            
            d_signal=[];
            for i=1:signal_length
                d_signal=[d_signal;d_chosed(signal_pnum(i),:)];
            end
            d_signal_average=mean(d_signal,1);

            d_ref=[];
            for i=1:ref_length
                d_ref=[d_ref;d_chosed(ref_pnum(i),:)];
            end
            d_ref_average=mean(d_ref,1);

            t=app.t_line;
            
            plot1=app.ShowSIGNALREFERENCECheckBox.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_2.Value;
            
            
            d_filtered=d_signal_average-d_ref_average;

            d_signal_average_tf=d_signal_average;
            d_ref_average_tf=d_ref_average;
            d_filtered_tf=d_filtered;

            frames_toaverage=app.frame_average.Value;
            frames_tot=length(t);
            % d_signal_average
            % d_ref_average
            % d_filtered
            for i=1:frames_tot-frames_toaverage+1
                d_signal_average_tf(i)=mean(d_signal_average_tf(i:i+frames_toaverage-1));
                d_ref_average_tf(i)=mean(d_ref_average_tf(i:i+frames_toaverage-1));
                d_filtered_tf(i)=mean(d_filtered_tf(i:i+frames_toaverage-1));
            end


            app.signal_record_tfilter=d_signal_average_tf;
            app.reference_record_tfilter=d_ref_average_tf;

            if plot1==1 && plot2==1
                cla(app.UIAxes3);                
                plot(app.UIAxes3,t,d_signal_average_tf,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average_tf,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3,t,d_filtered_tf,"Color",'b','LineWidth',2);
                legend(app.UIAxes3,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3,'off');


            elseif plot1==1 && plot2==0
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_signal_average_tf,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average_tf,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'signal averaged','reference signal averaged');

            elseif plot2==1 && plot1==0  
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_filtered_tf,"Color",'b','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'Filtered signal');

            end
           

            app.signal_record_tfilter=d_signal_average_tf;
            app.reference_record_tfilter=d_ref_average_tf;
            app.signal_filtered_record_tfilter=d_filtered_tf;


        end

        % Button pushed function: PlottfiltereddataButton
        function PlottfiltereddataButtonPushed(app, event)
            t=app.t_line;

            basesig=app.deltaADC_baseline;

            plot1=app.removereferencebaselineCheckBox.Value;
            plot2=app.removeADCbaselineCheckBox.Value;

            if plot1==0 && plot2==0
                d_signal_average_tf=app.signal_record_tfilter;
                d_ref_average_tf=app.reference_record_tfilter;
                d_filtered_tf=app.signal_filtered_record_tfilter;

                cla(app.UIAxes3_2);
                yyaxis (app.UIAxes3_2,'left')
                ylabel(app.UIAxes3_2,'raw data ADC code(/)'); 
                app.UIAxes3_2.YColor ='k';
                plot(app.UIAxes3_2,t,d_signal_average_tf,'-',"Color",'r','LineWidth',2);
                hold(app.UIAxes3_2,'on');
                plot(app.UIAxes3_2,t,d_ref_average_tf,'-',"Color",'k','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                yyaxis (app.UIAxes3_2,'right')
                ylabel(app.UIAxes3_2,'filtered data ADC code(/)'); 
                app.UIAxes3_2.YColor ='b';
                plot(app.UIAxes3_2,t,d_filtered_tf,'-',"Color",'b','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                legend(app.UIAxes3_2,'Signal averaged','Reference signal averaged', 'Filtered signal');

            elseif plot1==1 && plot2==0
                d_signal_average_tf=app.signal_record_tfilter_baselinecorrection_temp;
                d_ref_average_tf=app.reference_record_tfilter_baselinecorrection;
                d_filtered_tf=app.signal_filtered_record_tfilter_baselinecorrection_temp;

                cla(app.UIAxes3_2);
                yyaxis (app.UIAxes3_2,'left')
                ylabel(app.UIAxes3_2,'raw data ADC code(/)'); 
                app.UIAxes3_2.YColor ='k';
                plot(app.UIAxes3_2,t,d_signal_average_tf,'-',"Color",'r','LineWidth',2);
                hold(app.UIAxes3_2,'on');
                plot(app.UIAxes3_2,t,d_ref_average_tf,'-',"Color",'k','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                yyaxis (app.UIAxes3_2,'right')
                ylabel(app.UIAxes3_2,'filtered data ADC code(/)'); 
                app.UIAxes3_2.YColor ='b';
                plot(app.UIAxes3_2,t,d_filtered_tf,'-',"Color",'b','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                legend(app.UIAxes3_2,'Signal averaged','Reference signal averaged', 'Filtered signal');

            elseif plot1==0 && plot2==1
                d_ref_average_tf=app.reference_record_tfilter;
                d_signal_average_tf=app.signal_record_tfilter;
                d_filtered_tf=app.signal_filtered_record_tfilter;

                d_signal_average_tf=d_signal_average_tf-basesig;
                d_filtered_tf=d_filtered_tf-basesig;

                cla(app.UIAxes3_2);
                yyaxis (app.UIAxes3_2,'left')
                ylabel(app.UIAxes3_2,'raw data ADC code(/)'); 
                app.UIAxes3_2.YColor ='k';
                plot(app.UIAxes3_2,t,d_signal_average_tf,'-',"Color",'r','LineWidth',2);
                hold(app.UIAxes3_2,'on');
                plot(app.UIAxes3_2,t,d_ref_average_tf,'-',"Color",'k','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                yyaxis (app.UIAxes3_2,'right')
                ylabel(app.UIAxes3_2,'filtered data ADC code(/)'); 
                app.UIAxes3_2.YColor ='b';
                plot(app.UIAxes3_2,t,d_filtered_tf,'-',"Color",'b','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                legend(app.UIAxes3_2,'Signal averaged','Reference signal averaged', 'Filtered signal');
                
            else
                d_signal_average_tf=app.signal_record_tfilter_baselinecorrection;
                d_ref_average_tf=app.reference_record_tfilter_baselinecorrection;
                d_filtered_tf=app.signal_filtered_record_tfilter_baselinecorrection;
                                
                cla(app.UIAxes3_2);
                yyaxis (app.UIAxes3_2,'left')
                ylabel(app.UIAxes3_2,'raw data ADC code(/)'); 
                app.UIAxes3_2.YColor ='k';
                plot(app.UIAxes3_2,t,d_signal_average_tf,'-',"Color",'r','LineWidth',2);
                hold(app.UIAxes3_2,'on');
                plot(app.UIAxes3_2,t,d_ref_average_tf,'-',"Color",'k','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                yyaxis (app.UIAxes3_2,'right')
                ylabel(app.UIAxes3_2,'filtered data ADC code(/)'); 
                app.UIAxes3_2.YColor ='b';
                plot(app.UIAxes3_2,t,d_filtered_tf,'-',"Color",'b','LineWidth',2);
                hold(app.UIAxes3_2,'off');
                legend(app.UIAxes3_2,'Signal averaged','Reference signal averaged', 'Filtered signal');
            end
            
       
        end

        % Button pushed function: CleanallsignalselectionButton
        function CleanallsignalselectionButtonPushed(app, event)
            
            s=app.signal;
            s(find(s==0))=[];

            p=app.patches;
            slength=length(s);
            for i=1:slength
                set(p{s(i)},'edgecolor','none','FaceAlpha',1);
            end
            
            app.signal=zeros(1,1023);
            app.signal_lock=zeros(1,1023);


        end

        % Button pushed function: CleanallreferenceselectionButton
        function CleanallreferenceselectionButtonPushed(app, event)
            r=app.reference;
            r(find(r==0))=[];

            p=app.patches;
            rlength=length(r);
            for i=1:rlength
                set(p{r(i)},'edgecolor','none','FaceAlpha',1);
            end
            
            app.reference=zeros(1,1023);
            app.reference_lock=zeros(1,1023);
        end

        % Button pushed function: BaselinecorrectionofREFButton
        function BaselinecorrectionofREFButtonPushed(app, event)
            
            t=app.t_line;

            d_signal_average_tf=app.signal_record_tfilter;
            d_ref_average_tf=app.reference_record_tfilter;
            d_filtered_tf=app.signal_filtered_record_tfilter;

            plot1=app.ShowSIGNALREFERENCECheckBox.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_2.Value;

            d_length=length(d_filtered_tf);

            if d_length==0
                msgbox('Please first do time filtering','message')     
            else
                d_ref_average_tf_bc=detrend(d_ref_average_tf,'constant');
                app.reference_baseline=mean(d_ref_average_tf);
                app.baselinerefEditField.Value=mean(d_ref_average_tf);
                
                d_signal_average_tf_bc=d_signal_average_tf-mean(d_ref_average_tf);

                d_filtered_tf_bc=d_signal_average_tf_bc-d_ref_average_tf_bc;

                app.reference_record_tfilter_baselinecorrection=d_ref_average_tf_bc;
                app.signal_record_tfilter_baselinecorrection_temp=d_signal_average_tf_bc;
                app.signal_filtered_record_tfilter_baselinecorrection_temp=d_filtered_tf_bc;

                if plot1==1 && plot2==1
                cla(app.UIAxes3);                
                plot(app.UIAxes3,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                legend(app.UIAxes3,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3,'off');
               
                
                elseif plot1==1 && plot2==0
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'signal averaged','reference signal averaged');

                elseif plot2==1 && plot1==0  
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'Filtered signal');

            end


            end


        end

        % Button pushed function: ManuallycorrectSIGbaselineButton
        function ManuallycorrectSIGbaselineButtonPushed(app, event)
            t=app.t_line;

            d_ref_average_tf_bc=app.reference_record_tfilter_baselinecorrection;
            d_signal_average_tf_bc=app.signal_record_tfilter_baselinecorrection_temp;
            d_filtered_tf_bc=app.signal_filtered_record_tfilter_baselinecorrection_temp;

            plot1=app.ShowSIGNALREFERENCECheckBox.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_2.Value;

            d_length=length(d_ref_average_tf_bc);

            base=app.baselinesignalEditField.Value;
            app.deltaADC_baseline=base;

            if d_length==0
                msgbox('Please first do reference baseline correction','message')     
            else

                d_signal_average_tf_bc=d_signal_average_tf_bc-base;
                d_filtered_tf_bc=d_filtered_tf_bc-base;

                app.signal_record_tfilter_baselinecorrection=d_signal_average_tf_bc;
                app.signal_filtered_record_tfilter_baselinecorrection=d_filtered_tf_bc;


                if plot1==1 && plot2==1
                cla(app.UIAxes3);                
                plot(app.UIAxes3,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                legend(app.UIAxes3,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3,'off');
               
                
                elseif plot1==1 && plot2==0
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'signal averaged','reference signal averaged');

                elseif plot2==1 && plot1==0  
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'Filtered signal');

            end

            end
        end

        % Button pushed function: PlottinginonegoButton
        function PlottinginonegoButtonPushed(app, event)
            
            signal_pnum=app.signal_lock;
            ref_pnum=app.reference_lock;
            signal_length=length(signal_pnum);
            ref_length=length(ref_pnum);

            d=app.data_read;

            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            
            d_signal=[];
            for i=1:signal_length
                d_signal=[d_signal;d_chosed(signal_pnum(i),:)];
            end
            d_signal_average=mean(d_signal,1);

            d_ref=[];
            for i=1:ref_length
                d_ref=[d_ref;d_chosed(ref_pnum(i),:)];
            end
            d_ref_average=mean(d_ref,1);

            t=app.t_line;
            
            plot1=app.ShowSIGNALREFERENCECheckBox.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_2.Value;
            
            
            d_filtered=d_signal_average-d_ref_average;

            app.signal_record=d_signal_average;
            app.reference_record=d_ref_average;
            app.signal_filtered_record=d_filtered;


            d_signal_average_tf=d_signal_average;
            d_ref_average_tf=d_ref_average;
            d_filtered_tf=d_filtered;

            frames_toaverage=app.frame_average.Value;
            frames_tot=length(t);
            % d_signal_average
            % d_ref_average
            % d_filtered
            for i=1:frames_tot-frames_toaverage+1
                d_signal_average_tf(i)=mean(d_signal_average_tf(i:i+frames_toaverage-1));
                d_ref_average_tf(i)=mean(d_ref_average_tf(i:i+frames_toaverage-1));
                d_filtered_tf(i)=mean(d_filtered_tf(i:i+frames_toaverage-1));
            end

            app.signal_record_tfilter=d_signal_average_tf;
            app.reference_record_tfilter=d_ref_average_tf;
            app.signal_filtered_record_tfilter=d_filtered_tf;

        %ref baseline

                d_ref_average_tf_bc=detrend(d_ref_average_tf,'constant');
                app.reference_baseline=mean(d_ref_average_tf);
                app.baselinerefEditField.Value=mean(d_ref_average_tf);
                
                d_signal_average_tf_bc=d_signal_average_tf-mean(d_ref_average_tf);

                d_filtered_tf_bc=d_signal_average_tf_bc-d_ref_average_tf_bc;

                app.reference_record_tfilter_baselinecorrection=d_ref_average_tf_bc;

                app.signal_record_tfilter_baselinecorrection_temp=d_signal_average_tf_bc;
                app.signal_filtered_record_tfilter_baselinecorrection_temp=d_filtered_tf_bc;


        %sig baseline
                base=app.baselinesignalEditField.Value;
                app.deltaADC_baseline=base;
                
                d_signal_average_tf_bc=d_signal_average_tf_bc-base;
                d_filtered_tf_bc=d_filtered_tf_bc-base;

                app.signal_record_tfilter_baselinecorrection=d_signal_average_tf_bc;
                app.signal_filtered_record_tfilter_baselinecorrection=d_filtered_tf_bc;


            if plot1==1 && plot2==1
                cla(app.UIAxes3);                
                plot(app.UIAxes3,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                legend(app.UIAxes3,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3,'off');
               
                
            elseif plot1==1 && plot2==0
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'signal averaged','reference signal averaged');

            elseif plot2==1 && plot1==0  
                cla(app.UIAxes3);
                plot(app.UIAxes3,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                hold(app.UIAxes3,'off');
                legend(app.UIAxes3,'Filtered signal');

            end


        end

        % Button pushed function: AnalyzetfilteredButton
        function AnalyzetfilteredButtonPushed(app, event)
            t=app.t_line;

            d_signal_average_tf=app.signal_record_tfilter_baselinecorrection;
            d_ref_average_tf=app.reference_record_tfilter_baselinecorrection;
            d_filtered_tf=app.signal_filtered_record_tfilter_baselinecorrection;
                                
            cla(app.UIAxes3_3);
            plot(app.UIAxes3_3,t,d_signal_average_tf,'-',"Color",'r','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_ref_average_tf,'-',"Color",'k','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_filtered_tf,'-',"Color",'b','LineWidth',2);
            hold(app.UIAxes3_3,'off');
            legend(app.UIAxes3_3,'Signal averaged','Reference signal averaged', 'Filtered signal');
            brush(app.UIAxes3_3,'on');

            app.BaselinecorrectionofREFEditField.Value=app.reference_baseline;
            app.BaselinecorrectionofSIGEditField.Value=app.deltaADC_baseline;


        end

        % Button pushed function: AnalyzeRawButton
        function AnalyzeRawButtonPushed(app, event)
             t=app.t_line;

            d_signal_average=app.signal_record;
            d_ref_average=app.reference_record;

            d_ref_average_bc=detrend(d_ref_average,'constant');
            baseref=app.reference_baseline;
            deltabase=baseref-mean(d_ref_average);
            app.BaselinecorrectionofREFEditField.Value=mean(d_ref_average);               
            d_signal_average_bc=d_signal_average-mean(d_ref_average);
            d_filtered_bc=d_signal_average_bc-d_ref_average_bc;

            base=app.deltaADC_baseline-deltabase;

            d_signal_average_bc=d_signal_average_bc-base;
            d_filtered_bc=d_filtered_bc-base;


            cla(app.UIAxes3_3);
            plot(app.UIAxes3_3,t,d_signal_average_bc,'-',"Color",'r','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_ref_average_bc,'-',"Color",'k','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_filtered_bc,'-',"Color",'b','LineWidth',2);
            hold(app.UIAxes3_3,'off');
            legend(app.UIAxes3_3,'Signal averaged','Reference signal averaged', 'Filtered signal');
            brush(app.UIAxes3_3,'on');
          
          %  app.BaselinecorrectionofREFEditField.Value=app.reference_baseline;
            app.BaselinecorrectionofSIGEditField.Value=app.deltaADC_baseline;
        end

        % Button pushed function: AnalyzeTogetherButton
        function AnalyzeTogetherButtonPushed(app, event)
            t=app.t_line;

            d_filtered_tf_bc=app.signal_filtered_record_tfilter_baselinecorrection;
            
            d_signal_average=app.signal_record;
            d_ref_average=app.reference_record;
            d_ref_average_bc=detrend(d_ref_average,'constant');
            baseref=app.reference_baseline;
            deltabase=baseref-mean(d_ref_average); 
            d_signal_average_bc=d_signal_average-mean(d_ref_average);
            d_filtered_bc=d_signal_average_bc-d_ref_average_bc;
            base=app.deltaADC_baseline-deltabase;
            d_filtered_bc=d_filtered_bc-base;
            d_filtered_lpf=app.data_lpf;

            cla(app.UIAxes3_3);
            plot(app.UIAxes3_3,t,d_filtered_bc,'-',"Color",'r','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_filtered_tf_bc,'-',"Color",'b','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_filtered_lpf,'-',"Color",'k','LineWidth',2);
            hold(app.UIAxes3_3,'off');
            legend(app.UIAxes3_3,'RAW delta ADC signal','Time-filtered delta ADC signal','low pass filtered delta ADC signal');
            brush(app.UIAxes3_3,'on');

        end

        % Button pushed function: SmoothingButton
        function SmoothingButtonPushed(app, event)
            t=app.t_line;

            d_filtered_tf_bc=app.signal_filtered_record_tfilter_baselinecorrection;
            
            d_signal_average=app.signal_record;
            d_ref_average=app.reference_record;
            d_ref_average_bc=detrend(d_ref_average,'constant');
            baseref=app.reference_baseline;
            deltabase=baseref-mean(d_ref_average); 
            d_signal_average_bc=d_signal_average-mean(d_ref_average);
            d_filtered_bc=d_signal_average_bc-d_ref_average_bc;
            base=app.deltaADC_baseline-deltabase;
            d_filtered_bc=d_filtered_bc-base;

            
            if app.rlowessButton.Value==1
                d_filtered_bc_smoothed=smooth(t,d_filtered_bc,0.1,'rlowess');
                d_filtered_tf_bc_smoothed=smooth(t,d_filtered_tf_bc,0.1,'rlowess');
            elseif app.rloessButton.Value==1
                d_filtered_bc_smoothed=smooth(t,d_filtered_bc,0.1,'rloess');
                d_filtered_tf_bc_smoothed=smooth(t,d_filtered_tf_bc,0.1,'rloess');
            else
                d_filtered_bc_smoothed=smooth(t,d_filtered_bc,0.1,'sgolay');
                d_filtered_tf_bc_smoothed=smooth(t,d_filtered_tf_bc,0.1,'sgolay');
            end

            app.data_smoothed=d_filtered_bc_smoothed;
            app.data_tf_smoothed=d_filtered_tf_bc_smoothed;


            cla(app.UIAxes3_3);
            plot(app.UIAxes3_3,t,d_filtered_bc,'-',"Color",'r','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_filtered_tf_bc,'-',"Color",'b','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            Handle=plot(app.UIAxes3_3,t,d_filtered_bc_smoothed,'o',"Color",'b','LineWidth',2);
            app.analysishandle=Handle;
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_filtered_tf_bc_smoothed,'pentagram',"Color",'b','LineWidth',2);
            hold(app.UIAxes3_3,'off');
            legend(app.UIAxes3_3,'RAW delta ADC signal','Time-filtered delta ADC signal','RAW data smoothed','Time-filtered data smoothed');
            brush(app.UIAxes3_3,'on');
        end

        % Button pushed function: PickstartpointButton
        function PickstartpointButtonPushed(app, event)
            Handle=app.analysishandle;           
            xd = get(Handle, 'XData'); 
            brush = get(Handle, 'BrushData'); 
            brushed_x = xd(logical(brush)); 

            [Max_Value, Max_Index] = max(brush(:));
            

            leng=length(brushed_x);
            if leng>0
                app.StarttvalueEditField.Value=brushed_x(leng,1);
                
                app.t_analy_start=Max_Index;
                app.StarttindexEditField.Value=Max_Index;
            else
                app.StarttvalueEditField.Value=0;
            end


        end

        % Button pushed function: SmoothedonlyButton
        function SmoothedonlyButtonPushed(app, event)
            t=app.t_line;

            d_filtered_tf_bc=app.signal_filtered_record_tfilter_baselinecorrection;
            
            d_signal_average=app.signal_record;
            d_ref_average=app.reference_record;
            d_ref_average_bc=detrend(d_ref_average,'constant');
            baseref=app.reference_baseline;
            deltabase=baseref-mean(d_ref_average); 
            d_signal_average_bc=d_signal_average-mean(d_ref_average);
            d_filtered_bc=d_signal_average_bc-d_ref_average_bc;
            base=app.deltaADC_baseline-deltabase;
            d_filtered_bc=d_filtered_bc-base;


            if app.rlowessButton.Value==1
                d_filtered_bc_smoothed=smooth(t,d_filtered_bc,0.1,'rlowess');
                d_filtered_tf_bc_smoothed=smooth(t,d_filtered_tf_bc,0.1,'rlowess');
            elseif app.rloessButton.Value==1
                d_filtered_bc_smoothed=smooth(t,d_filtered_bc,0.1,'rloess');
                d_filtered_tf_bc_smoothed=smooth(t,d_filtered_tf_bc,0.1,'rloess');
            else
                d_filtered_bc_smoothed=smooth(t,d_filtered_bc,0.1,'sgolay');
                d_filtered_tf_bc_smoothed=smooth(t,d_filtered_tf_bc,0.1,'sgolay');
            end

            app.data_smoothed=d_filtered_bc_smoothed;
            app.data_tf_smoothed=d_filtered_tf_bc_smoothed;

            cla(app.UIAxes3_3);
            Handle=plot(app.UIAxes3_3,t,d_filtered_bc_smoothed,'o',"Color",'b','LineWidth',2);
            app.analysishandle=Handle;
            brush(app.UIAxes3_3,'on');
        end

        % Button pushed function: PickendpointButton
        function PickendpointButtonPushed(app, event)
            Handle=app.analysishandle;           
            xd = get(Handle, 'XData'); 
            brush = get(Handle, 'BrushData'); 
            brushed_x = xd(logical(brush)); 

            [Max_Value, Max_Index] = max(brush(:));

            leng=length(brushed_x);
            if leng>0
                app.EndtvalueEditField.Value=brushed_x(leng,1);
                
                app.t_analy_end=Max_Index;
                app.EndtindexEditField.Value=Max_Index;
            else
                app.EndtvalueEditField.Value=0;
            end

        end

        % Button pushed function: PickfittingrangeButton
        function PickfittingrangeButtonPushed(app, event)
            Handle=app.analysishandle;           
            xd = get(Handle, 'XData'); 
            brush = get(Handle, 'BrushData'); 
            brushed_x = xd(logical(brush)); 

            [Max_Value, Max_Index] = max(brush(:));
            Max_Index=find((brush == Max_Value));

            leng=length(brushed_x);
            if leng>0
                app.StarttvalueEditField.Value=brushed_x(1,1);
                app.EndtvalueEditField.Value=brushed_x(1,leng);
                app.StarttindexEditField.Value=Max_Index(1,1);
                app.EndtindexEditField.Value=Max_Index(1,leng);
       
                app.t_analy_start=Max_Index(1,1);
                app.t_analy_end=Max_Index(1,leng);


            else
                app.StarttvalueEditField.Value=0;
                app.EndtvalueEditField.Value=0;
            end
        end

        % Button pushed function: FittingButton
        function FittingButtonPushed(app, event)
            
            t=app.t_line;
            d_filtered_smoothed=app.data_smoothed';
            d_filtered_tf_smoothed=app.data_tf_smoothed';

            d_filtered_tf=app.signal_filtered_record_tfilter_baselinecorrection;

            d_signal_average=app.signal_record;
            d_ref_average=app.reference_record;
            d_ref_average_bc=detrend(d_ref_average,'constant');
            baseref=app.reference_baseline;
            deltabase=baseref-mean(d_ref_average); 
            d_signal_average_bc=d_signal_average-mean(d_ref_average);
            d_filtered_bc=d_signal_average_bc-d_ref_average_bc;
            base=app.deltaADC_baseline-deltabase;
            d_filtered=d_filtered_bc-base;


            t_startindex=app.t_analy_start;
            t_endindex=app.t_analy_end;

            tt=t(:,t_startindex:t_endindex);
            t_tmp=tt(1,1);
            tt=tt-tt(1,1);

            if app.RAWdataButton.Value==1
                d=d_filtered(:,t_startindex:t_endindex);
            elseif app.t_filtereddataButton.Value==1
                d=d_filtered_tf(:,t_startindex:t_endindex);
            elseif app.RAWdatasmoothedButton.Value==1
                d=d_filtered_smoothed(:,t_startindex:t_endindex);
            else
                d=d_filtered_tf_smoothed(:,t_startindex:t_endindex);
            end

            fun = @(x,xdata)x(1)+x(2)*exp((x(3)-xdata)/x(4));
            initial_estimation=[0,5,2,50];
            [s,resnorm]=lsqcurvefit(fun,initial_estimation,tt,d);
%{
            fun = @(x,xdata)x(1)*exp((x(2)-xdata)/x(3));
            initial_estimation=[5,2,50];
            [s,resnorm]=lsqcurvefit(fun,initial_estimation,tt,d);
%}

            app.SaturationvalueCEditField.Value=s(1,1);
            app.InitialconditionAEditField.Value=s(1,2);
            app.timeconstantEditField.Value=s(1,4);
            app.samplingtimedeviationt0EditField.Value=s(1,3);

%{ 
            app.InitialconditionAEditField.Value=s(1,1);
            app.timeconstantEditField.Value=s(1,3);
            app.samplingtimedeviationt0EditField.Value=s(1,2);
%}

            app.resnormEditField.Value=resnorm;

            d_fit=s(1)+s(2)*exp(-(tt-s(3))/s(4));
%           d_fit=s(1)*exp(-(tt-s(2))/s(3));

            tt=tt+t_tmp;
            
            if app.RAWdataButton.Value==1
                cla(app.UIAxes3_3);
                Handle=plot(app.UIAxes3_3,t,d_filtered,'o',"Color",'b','LineWidth',2);
                app.analysishandle=Handle;
                hold(app.UIAxes3_3,'on');
                plot(app.UIAxes3_3,tt,d_fit,'-',"Color",'r','LineWidth',4);
                hold(app.UIAxes3_3,'off');
                legend(app.UIAxes3_3,'RAW data','Fitted');
            elseif app.t_filtereddataButton.Value==1
                cla(app.UIAxes3_3);
                Handle=plot(app.UIAxes3_3,t,d_filtered_tf,'o',"Color",'b','LineWidth',2);
                app.analysishandle=Handle;
                hold(app.UIAxes3_3,'on');
                plot(app.UIAxes3_3,tt,d_fit,'-',"Color",'r','LineWidth',4);
                hold(app.UIAxes3_3,'off');
                legend(app.UIAxes3_3,'t-filtered data','Fitted');
            elseif app.RAWdatasmoothedButton.Value==1
                cla(app.UIAxes3_3);
                Handle=plot(app.UIAxes3_3,t,d_filtered_smoothed,'o',"Color",'b','LineWidth',2);
                app.analysishandle=Handle;
                hold(app.UIAxes3_3,'on');
                plot(app.UIAxes3_3,tt,d_fit,'-',"Color",'r','LineWidth',4);
                hold(app.UIAxes3_3,'off');
                legend(app.UIAxes3_3,'RAW data smoothed','Fitted');
            else
                cla(app.UIAxes3_3);
                Handle=plot(app.UIAxes3_3,t,d_filtered_tf_smoothed,'o',"Color",'b','LineWidth',2);
                app.analysishandle=Handle;
                hold(app.UIAxes3_3,'on');
                plot(app.UIAxes3_3,tt,d_fit,'-',"Color",'r','LineWidth',4);
                hold(app.UIAxes3_3,'off');
                legend(app.UIAxes3_3,'t-filtered data smoothed','Fitted');
            end
            

        end

        % Button pushed function: tfilteredonlyButton
        function tfilteredonlyButtonPushed(app, event)
            t=app.t_line;

            d_filtered_tf_bc=app.signal_filtered_record_tfilter_baselinecorrection;
            
            cla(app.UIAxes3_3);
            Handle=plot(app.UIAxes3_3,t,d_filtered_tf_bc,'-',"Color",'b','LineWidth',2);
            app.analysishandle=Handle;
            hold(app.UIAxes3_3,'off');
            
            brush(app.UIAxes3_3,'on');
        end

        % Button pushed function: FindsmallsignalinkButton
        function FindsmallsignalinkButtonPushed(app, event)
            d=app.data_read_autoscaled;
            app.ShowAutoscaledvalueCheckBox.Value=1;

            val=app.AutoselectrangeDropDown.Value;
             if val == "SensorMatrix"
                array_num=1;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));

             else 
                if val == "All 3 Matrixs"
                    d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));
                end
               
                if val == "SensorMatrix10um5x5"
                array_num=2;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
             end

            d_max=max(max(d));

            d_min=-1;
            d_max=3;
            app.MINADCvalueEditField.Value=d_min;
            app.MAXADCvalueEditField.Value=d_max;
            %d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));

            %{
            size=app.num_sets;
            x=[1:1:1023];
            y=[1:1:size];
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            %}

           %{
            surf(app.UIAxes,y,x,d1);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d2);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d3);
            hold(app.UIAxes,"off")

            %information=app.UIAxes.Position;
            %display(information);
            %app.UIAxes.Position = [0 0 552 272];
            %inforinner=app.UIAxes.InnerPosition;
            %display(inforinner);
            %app.UIAxes.InnerPosition = [0 0 300 200];
           %}

           %{
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            dd=[d1,d2,d3];
            imagesec(dd);
           %}
           % app.UIAxes.CLim = [d_min d_max];

            colorbar(app.UIAxes,'delete');
            c = colorbar(app.UIAxes); 
            c.Label.String = 'ADC CODE RANGE (/)';
            c.Limits = [d_min d_max];

            
            if d_max-d_min<=40
                c.Ticks = [d_min: 1 :d_max];
            elseif d_max-d_min <=450
                c.Ticks = [d_min: 10 :d_max];
            else
                c.Ticks = [d_min: 20 :d_max];
            end
            c.FontSize = 12;
            
            colormap(app.UIAxes, "parula"); 
            set(app.UIAxes,'CLim', [d_min d_max]);

            %barp=c.Position;
            c.Location = 'manual';
            c.Position = [0.9423 0.1 0.0113 0.85];
            %display(barp);


            msgbox('The colorbar has been reset','message')


            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            packet_set=app.SelectedsetnumEditField.Value;

            %%display(array_num);
            
            d=app.data_read_autoscaled;

            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,packet_set);
            %d_array=reshape(d_chosed,[32,32]);
            

            %initialization of selected pixel numbers
            app.signal = zeros(1,1023); %signal pixel number to anlyze
            app.reference = zeros(1,1023); % reference signal pixel number to analyze
            app.signal_lock = zeros(1,1023); %signal pixel number to anlyze
            app.reference_lock = zeros(1,1023); % reference signal pixel number to analyze


            %callbackfunction of patch
            function clickpatch(p,i)
                S=app.Select;
                if S==1
                    disp('patch1');
                    set(p,'edgecolor','r','FaceAlpha',0.4);
                    s=app.signal;
                    s(i)=i;
                    app.signal=s;
                    r=app.reference;
                    r(i)=0;
                    app.reference=r;

                    display(s(i));
                    
                elseif S==2
                    disp('patch2');
                    set(p,'edgecolor','k','FaceAlpha',0.4);
                    r=app.reference;
                    r(i)=i;
                    app.reference=r;
                    s=app.signal;
                    s(i)=0;
                    app.signal=s;

                    display(r(i));
                elseif S==0 
                    disp('patch3')
                    set(p,'edgecolor','none','FaceAlpha',1);
                    s=app.signal;
                    r=app.reference;
                    s(i)=0;
                    r(i)=0;
                    app.signal=s;
                    app.reference=r;

                    display(s(i));
                elseif S==3
                    disp(p.CData);
                    app.EditField.Value = p.CData;
                end
            end


            %unit pixel
            X=[0 0.5 1.5 2 1.5 0.5];
            Y=[(0.5)*3^(0.5) 0 0 (0.5)*3^(0.5) 3^(0.5) 3^(0.5)];

            %draw patches

            %1-960,30*32,15*2*32
            for j=1:15
                    for i=1:32
                        p{i+64*(j-1)}=patch(app.UIAxes,X+3*(j-1),Y+(i-1)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
                    for i=33:64
                        p{i+64*(j-1)}=patch(app.UIAxes,X+1.5+3*(j-1),Y+(64.5-i)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
            end
            %961-992,1*32
            for i=1:32
                p{i+960}=patch(app.UIAxes,X+45,Y+(i-1)*3^(0.5),d_chosed(i+960),'cdatamapping','scaled','edgecolor','none');
                %assign buttondown function
                set(p{i+960},'ButtonDownFcn',@(~,~)clickpatch(p{i+960},i+960));
            end  
            %993-1023,1*31
            for i=1:31
                p{i+992}=patch(app.UIAxes,X+46.5,Y+(32.5-i)*3^(0.5),d_chosed(i+992),'cdatamapping','scaled','edgecolor','none');
                set(p{i+992},'ButtonDownFcn',@(~,~)clickpatch(p{i+992},i+992));
            end
    
            app.patches=p;

            msgbox('Data Visualization of this Frame/Set is Completed','message')


        end

        % Button pushed function: PLOTrawdataButton_3
        function PLOTrawdataButton_3Pushed(app, event)
            signal_pnum=app.signal_lock;
            ref_pnum=app.reference_lock;
            signal_length=length(signal_pnum);
            ref_length=length(ref_pnum);

            d=app.data_read_autoscaled;

            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            
            d_signal=[];
            for i=1:signal_length
                d_signal=[d_signal;d_chosed(signal_pnum(i),:)];
            end
            d_signal_average=mean(d_signal,1);

            d_ref=[];
            for i=1:ref_length
                d_ref=[d_ref;d_chosed(ref_pnum(i),:)];
            end
            d_ref_average=mean(d_ref,1);

            t=app.t_line;
            
            plot1=app.ShowSIGNALREFERENCECheckBox_3.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_4.Value;
            
            
            d_filtered=d_signal_average-d_ref_average;
            if plot1==1 && plot2==1
                cla(app.UIAxes3_4);                
                plot(app.UIAxes3_4,t,d_signal_average,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3_4,t,d_filtered,"Color",'b','LineWidth',2);
                legend(app.UIAxes3_4,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3_4,'off');


            elseif plot1==1 && plot2==0
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_signal_average,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'signal averaged','reference signal averaged');

            elseif plot2==1 && plot1==0  
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_filtered,"Color",'b','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'Filtered signal');

            end
           

            app.signal_record=d_signal_average;
            app.reference_record=d_ref_average;
            app.signal_filtered_record=d_filtered;
        end

        % Button pushed function: TimefilteringperXframesButton_2
        function TimefilteringperXframesButton_2Pushed(app, event)
            
            signal_pnum=app.signal_lock;
            ref_pnum=app.reference_lock;
            signal_length=length(signal_pnum);
            ref_length=length(ref_pnum);

            d=app.data_read_autoscaled;

            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            
            d_signal=[];
            for i=1:signal_length
                d_signal=[d_signal;d_chosed(signal_pnum(i),:)];
            end
            d_signal_average=mean(d_signal,1);

            d_ref=[];
            for i=1:ref_length
                d_ref=[d_ref;d_chosed(ref_pnum(i),:)];
            end
            d_ref_average=mean(d_ref,1);

            t=app.t_line;
            
            plot1=app.ShowSIGNALREFERENCECheckBox_3.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_4.Value;
            
            
            d_filtered=d_signal_average-d_ref_average;

            d_signal_average_tf=d_signal_average;
            d_ref_average_tf=d_ref_average;
            d_filtered_tf=d_filtered;

            frames_toaverage=app.frame_average_2.Value;
            frames_tot=length(t);
            % d_signal_average
            % d_ref_average
            % d_filtered
            for i=1:frames_tot-frames_toaverage+1
                d_signal_average_tf(i)=mean(d_signal_average_tf(i:i+frames_toaverage-1));
                d_ref_average_tf(i)=mean(d_ref_average_tf(i:i+frames_toaverage-1));
                d_filtered_tf(i)=mean(d_filtered_tf(i:i+frames_toaverage-1));
            end


            app.signal_record_tfilter=d_signal_average_tf;
            app.reference_record_tfilter=d_ref_average_tf;

            if plot1==1 && plot2==1
                cla(app.UIAxes3_4);                
                plot(app.UIAxes3_4,t,d_signal_average_tf,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average_tf,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3_4,t,d_filtered_tf,"Color",'b','LineWidth',2);
                legend(app.UIAxes3_4,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3_4,'off');


            elseif plot1==1 && plot2==0
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_signal_average_tf,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average_tf,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'signal averaged','reference signal averaged');

            elseif plot2==1 && plot1==0  
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_filtered_tf,"Color",'b','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'Filtered signal');

            end
           

            app.signal_record_tfilter=d_signal_average_tf;
            app.reference_record_tfilter=d_ref_average_tf;
            app.signal_filtered_record_tfilter=d_filtered_tf;
        end

        % Button pushed function: SAVEPLOTRAWDATANOTESButton_2
        function SAVEPLOTRAWDATANOTESButton_2Pushed(app, event)
            d_signal_average=app.signal_record;
            d_ref_average=app.reference_record;
            d_filtered=app.signal_filtered_record;

            d_signal_average_tf=app.signal_record_tfilter;
            d_ref_average_tf=app.reference_record_tfilter;
            d_filtered_tf=app.signal_filtered_record_tfilter;

            d_signal_average_tf_bc=app.signal_record_tfilter_baselinecorrection;
            d_ref_average_tf_bc=app.reference_record_tfilter_baselinecorrection;
            d_filtered_tf_bc=app.signal_filtered_record_tfilter_baselinecorrection;


            t=app.t_line;

            d_signal_average=d_signal_average';
            d_ref_average=d_ref_average';
            d_filtered=d_filtered';
            d_signal_average_tf=d_signal_average_tf';
            d_ref_average_tf=d_ref_average_tf';
            d_filtered_tf=d_filtered_tf';
            d_signal_average_tf_bc=d_signal_average_tf_bc';
            d_ref_average_tf_bc=d_ref_average_tf_bc';
            d_filtered_tf_bc=d_filtered_tf_bc';
            t=t';

            data=app.ExportsensorselectiondatafileCheckBox_2.Value;
            image=app.ExportsensorselectionimageCheckBox_2.Value;


            datadir = uigetdir();
            app.dir_save=datadir;
            i_foldername=app.DatawillbestoredinFILEFOLDEREditField.Value;
            app.DatawillbestoredinFILEFOLDEREditField_2.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField_4.Value=i_foldername;
            notes_content=app.Notes_3.Value;

            timeframes=app.frame_average_2.Value;
            baseref=app.reference_baseline;
            basesig=app.deltaADC_baseline;

            cd (datadir);
            if exist(['plotdata',i_foldername],'dir')==0   
                mkdir(['plotdata',i_foldername])
                disp('Folder created.')
                msgbox('Folder created','message')
                 path = ['plotdata',i_foldername];


                 if data==1
                 myData_signal = fullfile(path,'myData_signal_autoscaled.dat');
                 writematrix(d_signal_average,myData_signal,'Delimiter','tab','WriteMode','append');
                 myData_reference = fullfile(path,'myData_reference_autoscaled.dat');
                 writematrix(d_ref_average,myData_reference,'Delimiter','tab','WriteMode','append');
                 myData_filtered = fullfile(path,'myData_filtered_autoscaled.dat');
                 writematrix(d_filtered,myData_filtered,'Delimiter','tab','WriteMode','append');
                 myData_signal_tf = fullfile(path,'myData_signal_timefiltered_autoscaled.dat');
                 writematrix(d_signal_average_tf,myData_signal_tf,'Delimiter','tab','WriteMode','append');
                 myData_reference_tf = fullfile(path,'myData_reference_timefiltered_autoscaled.dat');
                 writematrix(d_ref_average_tf,myData_reference_tf,'Delimiter','tab','WriteMode','append');
                 myData_filtered_tf = fullfile(path,'myData_filtered_timefiltered_autoscaled.dat');
                 writematrix(d_filtered_tf,myData_filtered_tf,'Delimiter','tab','WriteMode','append');
                 myData_signal_tf = fullfile(path,'myData_signal_timefiltered_baselinecorrection_autoscaled.dat');
                 writematrix(d_signal_average_tf_bc,myData_signal_tf,'Delimiter','tab','WriteMode','append');
                 myData_reference_tf = fullfile(path,'myData_reference_timefiltered_baselinecorrection_autoscaled.dat');
                 writematrix(d_ref_average_tf_bc,myData_reference_tf,'Delimiter','tab','WriteMode','append');
                 myData_filtered_tf = fullfile(path,'myData_filtered_timefiltered_baselinecorrection_autoscaled.dat');
                 writematrix(d_filtered_tf_bc,myData_filtered_tf,'Delimiter','tab','WriteMode','append');

                 myData_time = fullfile(path,'myData_time.dat');
                 writematrix(t,myData_time,'Delimiter','tab','WriteMode','append');
                 myData_COMBINED = fullfile(path,'myData_combined_autoscaled.dat');
                 %t; filtered signal; signal; reference
                 combined_data=[t,d_filtered,d_signal_average,d_ref_average,d_filtered_tf,d_signal_average_tf,d_ref_average_tf,d_filtered_tf_bc,d_signal_average_tf_bc,d_ref_average_tf_bc];
                 writematrix(combined_data,myData_COMBINED,'Delimiter','tab','WriteMode','append');


                 disp('Data saved.')
                 msgbox('Data saved','message')
                 end

                 if image==1

                 exportgraphics(app.UIAxes3_4,['plotdata',i_foldername,'\','selectedPixels.png']) 
                 disp('Image saved.')
                 msgbox('Image saved','message')

                 end

                 settingfilename = fullfile(path,'Notes.txt');
                 f=fopen(settingfilename,'a');
                 tNow = now;
                 fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                 fprintf(f,'Data filatered per: %i frames\r\n',timeframes);
                 fprintf(f,'Reference baseline ADC code value: %s \r\n',baseref);
                 fprintf(f,'Measured delta ADC baseline code value: %s \r\n',basesig);
                 fprintf(f,'Notes: %s \r\n',notes_content);
                 fclose(f);
                 disp('Notes saved.')
                 msgbox('Notes saved','message')

            else
                %fig = uifigure;
                msg = 'Folder alreade exists, data will be added to previous saved data.';
                title = 'Confirm Save';
                selection = uiconfirm(app.UIFigure,msg,title, ...
                    'Options',{'Keep Save','Only Save Notes','Cancel'});
        
                switch selection
                    case 'Keep Save'
                       
                        
                        if data==1
                         path = ['plotdata',i_foldername];
                         myData_signal = fullfile(path,'myData_signal_autoscaled.dat');
                         writematrix(d_signal_average,myData_signal,'Delimiter','tab','WriteMode','append');
                         myData_reference = fullfile(path,'myData_reference_autoscaled.dat');
                         writematrix(d_ref_average,myData_reference,'Delimiter','tab','WriteMode','append');
                         myData_filtered = fullfile(path,'myData_filtered_autoscaled.dat');
                         writematrix(d_filtered,myData_filtered,'Delimiter','tab','WriteMode','append');
                         
                         myData_signal_tf = fullfile(path,'myData_signal_timefiltered_autoscaled.dat');
                         writematrix(d_signal_average_tf,myData_signal_tf,'Delimiter','tab','WriteMode','append');
                         myData_reference_tf = fullfile(path,'myData_reference_timefiltered_autoscaled.dat');
                         writematrix(d_ref_average_tf,myData_reference_tf,'Delimiter','tab','WriteMode','append');
                         myData_filtered_tf = fullfile(path,'myData_filtered_timefiltered_autoscaled.dat');
                         writematrix(d_filtered_tf,myData_filtered_tf,'Delimiter','tab','WriteMode','append');

                        myData_signal_tf = fullfile(path,'myData_signal_timefiltered_baselinecorrection_autoscaled.dat');
                        writematrix(d_signal_average_tf_bc,myData_signal_tf,'Delimiter','tab','WriteMode','append');
                        myData_reference_tf = fullfile(path,'myData_reference_timefiltered_baselinecorrection_autoscaled.dat');
                        writematrix(d_ref_average_tf_bc,myData_reference_tf,'Delimiter','tab','WriteMode','append');
                        myData_filtered_tf = fullfile(path,'myData_filtered_timefiltered_baselinecorrection_autoscaled.dat');
                        writematrix(d_filtered_tf_bc,myData_filtered_tf,'Delimiter','tab','WriteMode','append');

                         myData_time = fullfile(path,'myData_time.dat');
                         writematrix(t,myData_time,'Delimiter','tab','WriteMode','append');
                         myData_COMBINED = fullfile(path,'myData_combined_autoscaled.dat');
                 %t; filtered signal; signal; reference
                        %combined_data=[t,d_filtered,d_signal_average,d_ref_average];
                         %t; filtered signal; signal; reference
                         writematrix(t,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_filtered,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_signal_average,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_ref_average,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_filtered_tf,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_signal_average_tf,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_ref_average_tf,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_filtered_tf_bc,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_signal_average_tf_bc,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                         writematrix(d_ref_average_tf_bc,myData_COMBINED,'Delimiter','tab','WriteMode','append');
                        end

                        if image==1

                         exportgraphics(app.UIAxes3_4,['plotdata',i_foldername,'\','selectedPixels.png'])   

                        end


                        settingfilename = fullfile(path,'Notes.txt');
                        f=fopen(settingfilename,'a');
                        tNow = now;
                        fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                        fprintf(f,'Data filatered per: %i frames\r\n',timeframes);
                        fprintf(f,'Reference baseline ADC code value: %s \r\n',baseref);
                        fprintf(f,'Measured delta ADC baseline code value: %s \r\n',basesig);
                        fprintf(f,'Notes: %s \r\n',notes_content);
                        fclose(f);
                        disp('Notes saved.')
                        msgbox('Notes saved','message')

                    case 'Only Save Notes'
                        path = ['plotdata',i_foldername];
                        settingfilename = fullfile(path,'Notes.txt');
                        f=fopen(settingfilename,'a');
                        tNow = now;
                        fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                        fprintf(f,'Data filatered per: %i frames\r\n',timeframes);
                        fprintf(f,'Reference baseline ADC code value: %s \r\n',baseref);
                        fprintf(f,'Measured delta ADC baseline code value: %s \r\n',basesig);
                        fprintf(f,'Notes: %s \r\n',notes_content);
                        fclose(f);
                        disp('Notes saved.')
                        msgbox('Notes saved','message')

                    case 'Cancel'
                            return
                end
                %disp('Warning: Folder already exists.')
                %msgbox('Folder alreade exists, data will be saved in this folder','warning','warn')
            end

           datapath=[datadir,'\',path];
           cd(datapath);
           Data_processed=importdata('myData_combined.dat');
           %Data_processed=Data_processed';
           Data_processed=Data_processed(:,1:4);

           app.UITable.Data=Data_processed;
           cd(datadir);
        end

        % Button pushed function: BaselinecorrectionofREFButton_2
        function BaselinecorrectionofREFButton_2Pushed(app, event)
            
            t=app.t_line;

            d_signal_average_tf=app.signal_record_tfilter;
            d_ref_average_tf=app.reference_record_tfilter;
            d_filtered_tf=app.signal_filtered_record_tfilter;

            plot1=app.ShowSIGNALREFERENCECheckBox_3.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_4.Value;

            d_length=length(d_filtered_tf);

            if d_length==0
                msgbox('Please first do time filtering','message')     
            else
                d_ref_average_tf_bc=detrend(d_ref_average_tf,'constant');
                app.reference_baseline=mean(d_ref_average_tf);
                app.baselinerefEditField_2.Value=mean(d_ref_average_tf);
                
                d_signal_average_tf_bc=d_signal_average_tf-mean(d_ref_average_tf);

                d_filtered_tf_bc=d_signal_average_tf_bc-d_ref_average_tf_bc;

                app.reference_record_tfilter_baselinecorrection=d_ref_average_tf_bc;
                app.signal_record_tfilter_baselinecorrection_temp=d_signal_average_tf_bc;
                app.signal_filtered_record_tfilter_baselinecorrection_temp=d_filtered_tf_bc;

                if plot1==1 && plot2==1
                cla(app.UIAxes3_4);                
                plot(app.UIAxes3_4,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3_4,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                legend(app.UIAxes3_4,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3_4,'off');
               
                
                elseif plot1==1 && plot2==0
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'signal averaged','reference signal averaged');

                elseif plot2==1 && plot1==0  
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'Filtered signal');

                end


            end
        end

        % Button pushed function: ManuallycorrectSIGbaselineButton_2
        function ManuallycorrectSIGbaselineButton_2Pushed(app, event)
            t=app.t_line;

            d_ref_average_tf_bc=app.reference_record_tfilter_baselinecorrection;
            d_signal_average_tf_bc=app.signal_record_tfilter_baselinecorrection_temp;
            d_filtered_tf_bc=app.signal_filtered_record_tfilter_baselinecorrection_temp;

            plot1=app.ShowSIGNALREFERENCECheckBox.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_2.Value;

            d_length=length(d_ref_average_tf_bc);

            base=app.baselinesignalEditField_2.Value;
            app.deltaADC_baseline=base;

            if d_length==0
                msgbox('Please first do reference baseline correction','message')     
            else

                d_signal_average_tf_bc=d_signal_average_tf_bc-base;
                d_filtered_tf_bc=d_filtered_tf_bc-base;

                app.signal_record_tfilter_baselinecorrection=d_signal_average_tf_bc;
                app.signal_filtered_record_tfilter_baselinecorrection=d_filtered_tf_bc;


                if plot1==1 && plot2==1
                cla(app.UIAxes3_4);                
                plot(app.UIAxes3_4,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3_4,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                legend(app.UIAxes3_4,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3_4,'off');
               
                
                elseif plot1==1 && plot2==0
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'signal averaged','reference signal averaged');

                elseif plot2==1 && plot1==0  
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'Filtered signal');

            end

            end
        end

        % Button pushed function: PlottinginonegoButton_2
        function PlottinginonegoButton_2Pushed(app, event)
            
            signal_pnum=app.signal_lock;
            ref_pnum=app.reference_lock;
            signal_length=length(signal_pnum);
            ref_length=length(ref_pnum);

            d=app.data_read_autoscaled;

            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            
            d_signal=[];
            for i=1:signal_length
                d_signal=[d_signal;d_chosed(signal_pnum(i),:)];
            end
            d_signal_average=mean(d_signal,1);

            d_ref=[];
            for i=1:ref_length
                d_ref=[d_ref;d_chosed(ref_pnum(i),:)];
            end
            d_ref_average=mean(d_ref,1);

            t=app.t_line;
            
            plot1=app.ShowSIGNALREFERENCECheckBox_3.Value;
            plot2=app.ShowSIGNALREFERENCECheckBox_4.Value;
            
            
            d_filtered=d_signal_average-d_ref_average;

            app.signal_record=d_signal_average;
            app.reference_record=d_ref_average;
            app.signal_filtered_record=d_filtered;


            d_signal_average_tf=d_signal_average;
            d_ref_average_tf=d_ref_average;
            d_filtered_tf=d_filtered;

            frames_toaverage=app.frame_average_2.Value;
            frames_tot=length(t);
            % d_signal_average
            % d_ref_average
            % d_filtered
            for i=1:frames_tot-frames_toaverage+1
                d_signal_average_tf(i)=mean(d_signal_average_tf(i:i+frames_toaverage-1));
                d_ref_average_tf(i)=mean(d_ref_average_tf(i:i+frames_toaverage-1));
                d_filtered_tf(i)=mean(d_filtered_tf(i:i+frames_toaverage-1));
            end

            app.signal_record_tfilter=d_signal_average_tf;
            app.reference_record_tfilter=d_ref_average_tf;
            app.signal_filtered_record_tfilter=d_filtered_tf;

        %ref baseline

                d_ref_average_tf_bc=detrend(d_ref_average_tf,'constant');
                app.reference_baseline=mean(d_ref_average_tf);
                app.baselinerefEditField_2.Value=mean(d_ref_average_tf);
                
                d_signal_average_tf_bc=d_signal_average_tf-mean(d_ref_average_tf);

                d_filtered_tf_bc=d_signal_average_tf_bc-d_ref_average_tf_bc;

                app.reference_record_tfilter_baselinecorrection=d_ref_average_tf_bc;

                app.signal_record_tfilter_baselinecorrection_temp=d_signal_average_tf_bc;
                app.signal_filtered_record_tfilter_baselinecorrection_temp=d_filtered_tf_bc;


        %sig baseline
                base=app.baselinesignalEditField_2.Value;
                app.deltaADC_baseline=base;
                
                d_signal_average_tf_bc=d_signal_average_tf_bc-base;
                d_filtered_tf_bc=d_filtered_tf_bc-base;

                app.signal_record_tfilter_baselinecorrection=d_signal_average_tf_bc;
                app.signal_filtered_record_tfilter_baselinecorrection=d_filtered_tf_bc;


            if plot1==1 && plot2==1
                cla(app.UIAxes3_4);                
                plot(app.UIAxes3_4,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                %legend(app.UIAxes3,'signal averaged','reference signal averaged');
                plot(app.UIAxes3_4,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                legend(app.UIAxes3_4,'Signal averaged','Reference signal averaged','Filtered signal');
                hold(app.UIAxes3_4,'off');
               
                
            elseif plot1==1 && plot2==0
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_signal_average_tf_bc,"Color",'r','LineWidth',2);
                hold(app.UIAxes3_4,'on');
                plot(app.UIAxes3_4,t,d_ref_average_tf_bc,"Color",'k','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'signal averaged','reference signal averaged');

            elseif plot2==1 && plot1==0  
                cla(app.UIAxes3_4);
                plot(app.UIAxes3_4,t,d_filtered_tf_bc,"Color",'b','LineWidth',2);
                hold(app.UIAxes3_4,'off');
                legend(app.UIAxes3_4,'Filtered signal');

            end

        end

        % Button pushed function: ShowmatrixButton_2
        function ShowmatrixButton_2Pushed(app, event)
            
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end

            %%
            app.MatrixselectionDropDown_2.Value=val;

            
            timepoint=app.SelectedtimesEditField.Value;
            t=app.t_line;
            [~,packet_set]=min(abs(t-timepoint));
            app.SelectedsetnumEditField.Value=packet_set;
            app.SetnumtoshowSlider.Value=packet_set;
            app.SelectedtimesEditField.Value=t(packet_set);

            app.SelectedsetnumEditField_2.Value=packet_set;
            app.SetnumtoshowSlider_2.Value=packet_set;
            app.SelectedtimesEditField_2.Value=t(packet_set);

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,packet_set);
            %d_array=reshape(d_chosed,[32,32]);
            

            %initialization of selected pixel numbers
            app.signal = zeros(1,1023); %signal pixel number to anlyze
            app.reference = zeros(1,1023); % reference signal pixel number to analyze
            app.signal_lock = zeros(1,1023); %signal pixel number to anlyze
            app.reference_lock = zeros(1,1023); % reference signal pixel number to analyze


            %callbackfunction of patch
            function clickpatch(p,i)
                S=app.Select;
                if S==1
                    disp('patch1');
                    set(p,'edgecolor','r','FaceAlpha',0.4);
                    s=app.signal;
                    s(i)=i;
                    app.signal=s;
                    r=app.reference;
                    r(i)=0;
                    app.reference=r;

                    display(s(i));
                    
                elseif S==2
                    disp('patch2');
                    set(p,'edgecolor','k','FaceAlpha',0.4);
                    r=app.reference;
                    r(i)=i;
                    app.reference=r;
                    s=app.signal;
                    s(i)=0;
                    app.signal=s;

                    display(r(i));
                elseif S==0 
                    disp('patch3')
                    set(p,'edgecolor','none','FaceAlpha',1);
                    s=app.signal;
                    r=app.reference;
                    s(i)=0;
                    r(i)=0;
                    app.signal=s;
                    app.reference=r;

                    display(s(i));
                elseif S==3
                    disp(p.CData);
                    app.EditField.Value = p.CData;
                end
            end


            %unit pixel
            X=[0 0.5 1.5 2 1.5 0.5];
            Y=[(0.5)*3^(0.5) 0 0 (0.5)*3^(0.5) 3^(0.5) 3^(0.5)];

            %draw patches

            %1-960,30*32,15*2*32
            for j=1:15
                    for i=1:32
                        p{i+64*(j-1)}=patch(app.UIAxes,X+3*(j-1),Y+(i-1)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
                    for i=33:64
                        p{i+64*(j-1)}=patch(app.UIAxes,X+1.5+3*(j-1),Y+(64.5-i)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
            end
            %961-992,1*32
            for i=1:32
                p{i+960}=patch(app.UIAxes,X+45,Y+(i-1)*3^(0.5),d_chosed(i+960),'cdatamapping','scaled','edgecolor','none');
                %assign buttondown function
                set(p{i+960},'ButtonDownFcn',@(~,~)clickpatch(p{i+960},i+960));
            end  
            %993-1023,1*31
            for i=1:31
                p{i+992}=patch(app.UIAxes,X+46.5,Y+(32.5-i)*3^(0.5),d_chosed(i+992),'cdatamapping','scaled','edgecolor','none');
                set(p{i+992},'ButtonDownFcn',@(~,~)clickpatch(p{i+992},i+992));
            end
    
            app.patches=p;

msgbox('Data Visualization of this Frame/Set is Completed','message')


        end

        % Button pushed function: lowpassfilterButton
        function lowpassfilterButtonPushed(app, event)
        %{    
            t=app.t_line;

            d_filtered_tf_bc=app.signal_filtered_record_tfilter_baselinecorrection;
            fpass=app.fpassEditField.Value;
            d_filtered=lowpass(d_filtered_tf_bc,fpass);
            
            cla(app.UIAxes3_3);
            Handle=plot(app.UIAxes3_3,t,d_filtered,'-',"Color",'b','LineWidth',2);
            app.analysishandle=Handle;
            hold(app.UIAxes3_3,'off');
            
            brush(app.UIAxes3_3,'on');
        %}
            t=app.t_line;

            d_signal_average=app.signal_record;
            d_ref_average=app.reference_record;

            d_ref_average_bc=detrend(d_ref_average,'constant');
            baseref=app.reference_baseline;
            deltabase=baseref-mean(d_ref_average);
            app.BaselinecorrectionofREFEditField.Value=mean(d_ref_average);               
            d_signal_average_bc=d_signal_average-mean(d_ref_average);
            d_filtered_bc=d_signal_average_bc-d_ref_average_bc;

            base=app.deltaADC_baseline-deltabase;

            d_signal_average_bc=d_signal_average_bc-base;
            d_filtered_bc=d_filtered_bc-base;

            fpass=app.fpassEditField.Value;
            d_signal_average_bc=lowpass(d_signal_average_bc,fpass);
            d_ref_average_bc=lowpass(d_ref_average_bc,fpass);
            d_filtered_bc=d_signal_average_bc-d_ref_average_bc;
                                
            cla(app.UIAxes3_3);
            plot(app.UIAxes3_3,t,d_signal_average_bc,'-',"Color",'r','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_ref_average_bc,'-',"Color",'k','LineWidth',2);
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,t,d_filtered_bc,'-',"Color",'b','LineWidth',2);
            hold(app.UIAxes3_3,'off');
            legend(app.UIAxes3_3,'Signal averaged','Reference signal averaged', 'Filtered signal');
            brush(app.UIAxes3_3,'on');

            app.data_lpf=d_filtered_bc;
          

        end

        % Button pushed function: FindHighestResponseSetButton_2
        function FindHighestResponseSetButton_2Pushed(app, event)
            val=app.MatrixselectionDropDown_2.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
                
            %%%%
            app.MatrixselectionDropDown.Value=val;

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox_2.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            %d_array=reshape(d_chosed,[32,32]);
            
            [max_value, position_max]=max(d_chosed(:));
            [x,y]=ind2sub(size(d_chosed),position_max);
            app.SelectedsetnumEditField.Value=y;
            app.SetnumtoshowSlider.Value=y;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(y);

            %%
            app.SelectedsetnumEditField_2.Value=y;
            app.SetnumtoshowSlider_2.Value=y;
            app.SelectedtimesEditField_2.Value=t(y);
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            d_raw=app.data_read;
            d_autoscaled=app.data_read_autoscaled;

            val=app.MatrixselectionDropDown_2.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end

            set=app.SelectedsetnumEditField_2.Value;

            d_raw_pick=d_raw(1025*(array_num-1)+3:1025*(array_num-1)+1025,set);
            d_autoscaled_pick=d_autoscaled(1025*(array_num-1)+3:1025*(array_num-1)+1025,set);

            X_raw=sort(d_raw_pick);
            X_autoscaled=sort(d_autoscaled_pick);
 
            Y=[0.5/1023 : 1/1023 : (1023-0.5)/1023]';
            
            cla(app.UIAxes3_5);
            plot(app.UIAxes3_5,X_raw,Y,"Color",'r','LineWidth',2);

            cla(app.UIAxes3_6);
            plot(app.UIAxes3_6,X_autoscaled,Y,"Color",'r','LineWidth',2);
               

        end

        % Value changed function: SetnumtoshowSlider_2
        function SetnumtoshowSlider_2ValueChanged(app, event)
            
            value = fix(app.SetnumtoshowSlider_2.Value);
            t=app.t_line;
            app.SelectedsetnumEditField.Value = value;
            app.SelectedtimesEditField.Value = t(value);

            app.SetnumtoshowSlider.Value = value;
            app.SelectedsetnumEditField_2.Value = value;
            app.SelectedtimesEditField_2.Value = t(value);
        end

        % Button pushed function: ShowCDFplotButton
        function ShowCDFplotButtonPushed(app, event)
            val=app.MatrixselectionDropDown_2.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end

            %%
            app.MatrixselectionDropDown.Value=val;

            
            packet_set=app.SelectedsetnumEditField_2.Value;
            app.SetnumtoshowSlider.Value=packet_set;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(packet_set);

            %%
            app.SetnumtoshowSlider_2.Value=packet_set;
            app.SelectedtimesEditField_2.Value=t(packet_set);


            d_raw=app.data_read;
            d_autoscaled=app.data_read_autoscaled;

            d_raw_pick=d_raw(1025*(array_num-1)+3:1025*(array_num-1)+1025,packet_set);
            d_autoscaled_pick=d_autoscaled(1025*(array_num-1)+3:1025*(array_num-1)+1025,packet_set);

            X_raw=sort(d_raw_pick);
            X_autoscaled=sort(d_autoscaled_pick);
 
            Y=[0.5/1023 : 1/1023 : (1023-0.5)/1023]';
            
            cla(app.UIAxes3_5);
            plot(app.UIAxes3_5,X_raw,Y,"Color",'r','LineWidth',2);

            cla(app.UIAxes3_6);
            plot(app.UIAxes3_6,X_autoscaled,Y,"Color",'r','LineWidth',2);
        end

        % Button pushed function: ShowCDFplotButton_2
        function ShowCDFplotButton_2Pushed(app, event)
             val=app.MatrixselectionDropDown_2.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end

            %%
            app.MatrixselectionDropDown.Value=val;

            
            timepoint=app.SelectedtimesEditField_2.Value;
            t=app.t_line;
            [~,packet_set]=min(abs(t-timepoint));
            app.SelectedsetnumEditField.Value=packet_set;
            app.SetnumtoshowSlider.Value=packet_set;
            app.SelectedtimesEditField.Value=t(packet_set);

            app.SelectedsetnumEditField_2.Value=packet_set;
            app.SetnumtoshowSlider_2.Value=packet_set;
            app.SelectedtimesEditField_2.Value=t(packet_set);

            d_raw=app.data_read;
            d_autoscaled=app.data_read_autoscaled;

            d_raw_pick=d_raw(1025*(array_num-1)+3:1025*(array_num-1)+1025,packet_set);
            d_autoscaled_pick=d_autoscaled(1025*(array_num-1)+3:1025*(array_num-1)+1025,packet_set);

            X_raw=sort(d_raw_pick);
            X_autoscaled=sort(d_autoscaled_pick);
 
            Y=[0.5/1023 : 1/1023 : (1023-0.5)/1023]';
            
            cla(app.UIAxes3_5);
            plot(app.UIAxes3_5,X_raw,Y,"Color",'r','LineWidth',2);

            cla(app.UIAxes3_6);
            plot(app.UIAxes3_6,X_autoscaled,Y,"Color",'r','LineWidth',2);
        end

        % Button pushed function: SAVEPLOTIMAGEButton_2
        function SAVEPLOTIMAGEButton_2Pushed(app, event)
            datadir=app.dir_save;


            i_foldername=app.DatawillbestoredinFILEFOLDEREditField_4.Value;
            app.DatawillbestoredinFILEFOLDEREditField_2.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField_3.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField.Value=i_foldername;
            app.DatawillbestoredinFILEFOLDEREditField_4.Value=i_foldername;
            notes_content=app.Notes_2.Value;

            cd (datadir);
            if exist(['plotdata',i_foldername],'dir')==0   
                mkdir(['plotdata',i_foldername])
                disp('Folder created.')
                msgbox('Folder created','message')
                 path = ['plotdata',i_foldername];

                 exportgraphics(app.UIAxes3_2,['plotdata',i_foldername,'\','selectedPixels_yyaxis.png'])   
                 disp('Image saved.')
                 msgbox('Image saved','message')

 
                 settingfilename = fullfile(path,'Notes.txt');
                 f=fopen(settingfilename,'a');
                 tNow = now;
                 fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                 fprintf(f,'Notes: %s \r\n',notes_content);
                 fclose(f);
                 disp('Notes saved.')
                 msgbox('Notes saved','message')

            else
                %fig = uifigure;
                msg = 'Folder alreade exists, yyaxis plot will be added to this folder and replace the previous one.';
                title = 'Confirm Save';
                selection = uiconfirm(app.UIFigure,msg,title, ...
                    'Options',{'Keep Save','Only Save Notes','Cancel'});
        
                switch selection
                    case 'Keep Save'
                       

                         path = ['plotdata',i_foldername];

                         exportgraphics(app.UIAxes3_5,['plotdata',i_foldername,'\','CDF_rawdata.png'])   
                         disp('Image saved.')
                         msgbox('Image saved','message')

                         exportgraphics(app.UIAxes3_6,['plotdata',i_foldername,'\','CDF_autoscaled_ΔADC.png'])   
                         disp('Image saved.')
                         msgbox('Image saved','message')


                        settingfilename = fullfile(path,'Notes.txt');
                        f=fopen(settingfilename,'a');
                        tNow = now;
                        fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                        fprintf(f,'Notes: %s \r\n',notes_content);
                        fclose(f);
                        disp('Notes saved.')
                        msgbox('Notes saved','message')

                    case 'Only Save Notes'
                        path = ['plotdata',i_foldername];
                        settingfilename = fullfile(path,'Notes.txt');
                        f=fopen(settingfilename,'a');
                        tNow = now;
                        fprintf(f,'Time: %s \r\n',datestr(tNow,'dd-mmm-yyyy HH:MM:SS.FFF'));
                        fprintf(f,'Notes: %s \r\n',notes_content);
                        fclose(f);
                        disp('Notes saved.')
                        msgbox('Notes saved','message')

                    case 'Cancel'
                            return
                end
                %disp('Warning: Folder already exists.')
                %msgbox('Folder alreade exists, data will be saved in this folder','warning','warn')
            end
        end

        % Button pushed function: 
        % EmptychipdataFilefolderSearchandSelectButton
        function EmptychipdataFilefolderSearchandSelectButtonPushed(app, event)
            maindir = uigetdir();
            app.myDatafilealignpath = maindir;
            app.iftoalign=1;
        end

        % Button pushed function: SelectALLsetsandImportdataButton_2
        function SelectALLsetsandImportdataButton_2Pushed(app, event)
            myDatapath = app.myDatafilealignpath;
            
            %%display(myDatapath);
            %%display(NumofSet);
            str2 = "\myData.dat";
            str=append(myDatapath,str2);
            %%display(str);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Importdata  

            %%READ THE FIRST PACKET (DATE NUMBER INCLUDED)
            
            fid = fopen(str,'r'); 
            t1=fgets(fid);
            tt1=textscan(t1,'%f %s %f :%f :%f');
            %dump=fgets(fid);
            data=textscan(fid,'%f', 'Delimiter', ' ', 'CollectOutput', 1);
            d1=data{1};
            % d1(1)  packet number 
            % d1(2)  1st array
            % d1(3-1026)  data of 1st array   %n+2
            % d1(1027)  1st array
            % d1(1028-2051)  data of 1st array  %n+1027
            % d1(2052)  1st array
            % d1(2053-3076)  data of 1st array  %n+2051
            % d1(3077)  dump data/date information
            d=d1;

            N=1;
            Length=3077;
            %%READ THE FOLLOWING PACKETS (DATE NUMBER LOST)
            while Length==3077
            t_next=fgets(fid);
            tt_next=textscan(t_next,'%s %f :%f :%f');%lost 1 date number due to previous textscan
            %dump=fgets(fid);
            data=textscan(fid,'%f', 'Delimiter', ' ', 'CollectOutput', 1);
            d_next=data{1};
            Length=length(d_next);

            N=N+1;
    
                if Length==3077
                    d=[d,d_next];
                end
            end

            d(3077,:)=[];
            d=[d,d_next];

            
            %N=total sets

            %sum each pixel among all time
            d_sum=sum(d,2);
            %average
            d_average=d_sum/N;


            app.data_align=d_average;
           
            app.ShowaveragedprintedemptychipwithrefgasButton.Enable='on';


        end

        % Button pushed function: ShowprintedarrayrawarraydataButton
        function ShowprintedarrayrawarraydataButtonPushed(app, event)
            %%%Set ADC range
            %All 3 matrix in default mode
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_aligned; 
            else
                d=app.data_aligned_autoscaled;
            end
            val=app.AutoselectrangeDropDown.Value;
             if val == "SensorMatrix"
                array_num=1;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));

             else 
                if val == "All 3 Matrixs"
                    d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));
                end
               
                if val == "SensorMatrix10um5x5"
                array_num=2;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
             end

            d_max=max(max(d));

            app.MINADCvalueEditField.Value=d_min;
            app.MAXADCvalueEditField.Value=d_max;
            %d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));

            %{
            size=app.num_sets;
            x=[1:1:1023];
            y=[1:1:size];
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            %}

           %{
            surf(app.UIAxes,y,x,d1);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d2);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d3);
            hold(app.UIAxes,"off")

            %information=app.UIAxes.Position;
            %display(information);
            %app.UIAxes.Position = [0 0 552 272];
            %inforinner=app.UIAxes.InnerPosition;
            %display(inforinner);
            %app.UIAxes.InnerPosition = [0 0 300 200];
           %}

           %{
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            dd=[d1,d2,d3];
            imagesec(dd);
           %}
           % app.UIAxes.CLim = [d_min d_max];

            colorbar(app.UIAxes,'delete');
            c = colorbar(app.UIAxes); 
            c.Label.String = 'ADC CODE RANGE (/)';
            c.Limits = [d_min d_max];

            if d_max-d_min<=40
                c.Ticks = [d_min: 1 :d_max];
            elseif d_max-d_min <=450
                c.Ticks = [d_min: 10 :d_max];
            else
                c.Ticks = [d_min: 20 :d_max];
            end
            c.FontSize = 12;
            
            colormap(app.UIAxes, "parula"); 
            set(app.UIAxes,'CLim', [d_min d_max]);

            %barp=c.Position;
            c.Location = 'manual';
            c.Position = [0.9423 0.1 0.0113 0.85];
            %display(barp);


            msgbox('The colorbar has been reset','message')


            %%%Find Highest response set num
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
                
            %%%%
            app.MatrixselectionDropDown_2.Value=val;

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_aligned; 
            else
                d=app.data_aligned_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            %d_array=reshape(d_chosed,[32,32]);
            
            [max_value, position_max]=max(d_chosed(:));
            [x,y]=ind2sub(size(d_chosed),position_max);
            app.SelectedsetnumEditField.Value=y;
            app.SetnumtoshowSlider.Value=y;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(y);

            %%
            app.SelectedsetnumEditField_2.Value=y;
            app.SetnumtoshowSlider_2.Value=y;
            app.SelectedtimesEditField_2.Value=t(y);

            %%%create patch/show patch
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            packet_set=app.SelectedsetnumEditField.Value;

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_aligned; 
            else
                d=app.data_aligned_autoscaled;
            end 
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,packet_set);
            %d_array=reshape(d_chosed,[32,32]);


            %initialization of selected pixel numbers
            app.signal = zeros(1,1023); %signal pixel number to anlyze
            app.reference = zeros(1,1023); % reference signal pixel number to analyze
            app.signal_lock = zeros(1,1023); %signal pixel number to anlyze
            app.reference_lock = zeros(1,1023); % reference signal pixel number to analyze

            %callbackfunction of patch
            function clickpatch(p,i)
                S=app.Select;
                if S==1
                    disp('patch1');
                    set(p,'edgecolor','r','FaceAlpha',0.4);
                    s=app.signal;
                    s(i)=i;
                    app.signal=s;
                    r=app.reference;
                    r(i)=0;
                    app.reference=r;

                    display(s(i));
                    
                elseif S==2
                    disp('patch2');
                    set(p,'edgecolor','k','FaceAlpha',0.4);
                    r=app.reference;
                    r(i)=i;
                    app.reference=r;
                    s=app.signal;
                    s(i)=0;
                    app.signal=s;

                    display(r(i));
                elseif S==0 
                    disp('patch3')
                    set(p,'edgecolor','none','FaceAlpha',1);
                    s=app.signal;
                    r=app.reference;
                    s(i)=0;
                    r(i)=0;
                    app.signal=s;
                    app.reference=r;

                    display(s(i));
                elseif S==3
                    disp(p.CData);
                    app.EditField.Value = p.CData;
                end
            end


            %unit pixel
            X=[0 0.5 1.5 2 1.5 0.5];
            Y=[(0.5)*3^(0.5) 0 0 (0.5)*3^(0.5) 3^(0.5) 3^(0.5)];

            %draw patches

            %1-960,30*32,15*2*32
            for j=1:15
                    for i=1:32
                        p{i+64*(j-1)}=patch(app.UIAxes,X+3*(j-1),Y+(i-1)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
                    for i=33:64
                        p{i+64*(j-1)}=patch(app.UIAxes,X+1.5+3*(j-1),Y+(64.5-i)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
            end
            %961-992,1*32
            for i=1:32
                p{i+960}=patch(app.UIAxes,X+45,Y+(i-1)*3^(0.5),d_chosed(i+960),'cdatamapping','scaled','edgecolor','none');
                %assign buttondown function
                set(p{i+960},'ButtonDownFcn',@(~,~)clickpatch(p{i+960},i+960));
            end  
            %993-1023,1*31
            for i=1:31
                p{i+992}=patch(app.UIAxes,X+46.5,Y+(32.5-i)*3^(0.5),d_chosed(i+992),'cdatamapping','scaled','edgecolor','none');
                set(p{i+992},'ButtonDownFcn',@(~,~)clickpatch(p{i+992},i+992));
            end

            app.patches=p;


        msgbox('Data Visualization of this Frame/Set is Completed','message')
        end

        % Button pushed function: ShowautoscaledprintedrawdataButton
        function ShowautoscaledprintedrawdataButtonPushed(app, event)
            %%%Set ADC range
            %All 3 matrix in default mode
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_aligned; 
            else
                d=app.data_aligned_autoscaled;
            end
            val=app.AutoselectrangeDropDown.Value;
             if val == "SensorMatrix"
                array_num=1;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));

             else 
                if val == "All 3 Matrixs"
                    d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));
                end
               
                if val == "SensorMatrix10um5x5"
                array_num=2;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                d=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
                d_min=min(min(d(1:1023,:)));
                end
             end

            d_max=max(max(d));

            app.MINADCvalueEditField.Value=d_min;
            app.MAXADCvalueEditField.Value=d_max;
            %d_min=min(min([min(d(3:1025,:));min(d(1028:2050,:));min(d(2053:3075,:))]));

            %{
            size=app.num_sets;
            x=[1:1:1023];
            y=[1:1:size];
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            %}

           %{
            surf(app.UIAxes,y,x,d1);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d2);
            hold(app.UIAxes,"on")
            surf(app.UIAxes,y,x,d3);
            hold(app.UIAxes,"off")

            %information=app.UIAxes.Position;
            %display(information);
            %app.UIAxes.Position = [0 0 552 272];
            %inforinner=app.UIAxes.InnerPosition;
            %display(inforinner);
            %app.UIAxes.InnerPosition = [0 0 300 200];
           %}

           %{
            d1=d(3:1025,:);
            d2=d(1028:2050,:);
            d3=d(2053:3075,:);
            dd=[d1,d2,d3];
            imagesec(dd);
           %}
           % app.UIAxes.CLim = [d_min d_max];

            colorbar(app.UIAxes,'delete');
            c = colorbar(app.UIAxes); 
            c.Label.String = 'ADC CODE RANGE (/)';
            c.Limits = [d_min d_max];

            if d_max-d_min<=40
                c.Ticks = [d_min: 1 :d_max];
            elseif d_max-d_min <=450
                c.Ticks = [d_min: 10 :d_max];
            else
                c.Ticks = [d_min: 20 :d_max];
            end
            c.FontSize = 12;
            
            colormap(app.UIAxes, "parula"); 
            set(app.UIAxes,'CLim', [d_min d_max]);

            %barp=c.Position;
            c.Location = 'manual';
            c.Position = [0.9423 0.1 0.0113 0.85];
            %display(barp);


            msgbox('The colorbar has been reset','message')


            %%%Find Highest response set num
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
                
            %%%%
            app.MatrixselectionDropDown_2.Value=val;

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_aligned; 
            else
                d=app.data_aligned_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            y=1;
            app.SelectedsetnumEditField.Value=y;
            app.SetnumtoshowSlider.Value=y;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(y);

            %%
            app.SelectedsetnumEditField_2.Value=y;
            app.SetnumtoshowSlider_2.Value=y;
            app.SelectedtimesEditField_2.Value=t(y);

            %%%create patch/show patch
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
            
            packet_set=app.SelectedsetnumEditField.Value;

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_aligned; 
            else
                d=app.data_aligned_autoscaled;
            end 
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,packet_set);
            %d_array=reshape(d_chosed,[32,32]);


            %initialization of selected pixel numbers
            app.signal = zeros(1,1023); %signal pixel number to anlyze
            app.reference = zeros(1,1023); % reference signal pixel number to analyze
            app.signal_lock = zeros(1,1023); %signal pixel number to anlyze
            app.reference_lock = zeros(1,1023); % reference signal pixel number to analyze

            %callbackfunction of patch
            function clickpatch(p,i)
                S=app.Select;
                if S==1
                    disp('patch1');
                    set(p,'edgecolor','r','FaceAlpha',0.4);
                    s=app.signal;
                    s(i)=i;
                    app.signal=s;
                    r=app.reference;
                    r(i)=0;
                    app.reference=r;

                    display(s(i));
                    
                elseif S==2
                    disp('patch2');
                    set(p,'edgecolor','k','FaceAlpha',0.4);
                    r=app.reference;
                    r(i)=i;
                    app.reference=r;
                    s=app.signal;
                    s(i)=0;
                    app.signal=s;

                    display(r(i));
                elseif S==0 
                    disp('patch3')
                    set(p,'edgecolor','none','FaceAlpha',1);
                    s=app.signal;
                    r=app.reference;
                    s(i)=0;
                    r(i)=0;
                    app.signal=s;
                    app.reference=r;

                    display(s(i));
                elseif S==3
                    disp(p.CData);
                    app.EditField.Value = p.CData;
                end
            end


            %unit pixel
            X=[0 0.5 1.5 2 1.5 0.5];
            Y=[(0.5)*3^(0.5) 0 0 (0.5)*3^(0.5) 3^(0.5) 3^(0.5)];

            %draw patches

            %1-960,30*32,15*2*32
            for j=1:15
                    for i=1:32
                        p{i+64*(j-1)}=patch(app.UIAxes,X+3*(j-1),Y+(i-1)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
                    for i=33:64
                        p{i+64*(j-1)}=patch(app.UIAxes,X+1.5+3*(j-1),Y+(64.5-i)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
            end
            %961-992,1*32
            for i=1:32
                p{i+960}=patch(app.UIAxes,X+45,Y+(i-1)*3^(0.5),d_chosed(i+960),'cdatamapping','scaled','edgecolor','none');
                %assign buttondown function
                set(p{i+960},'ButtonDownFcn',@(~,~)clickpatch(p{i+960},i+960));
            end  
            %993-1023,1*31
            for i=1:31
                p{i+992}=patch(app.UIAxes,X+46.5,Y+(32.5-i)*3^(0.5),d_chosed(i+992),'cdatamapping','scaled','edgecolor','none');
                set(p{i+992},'ButtonDownFcn',@(~,~)clickpatch(p{i+992},i+992));
            end

            app.patches=p;


        msgbox('Data Visualization of this Frame/Set is Completed','message')
        end

        % Button pushed function: PLOTButton_2
        function PLOTButton_2Pushed(app, event)
            d_raw=app.data_read;
            d_empty=app.data_aligned;

            val=app.MatrixselectionDropDown_3.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end

            
            d_raw_pick=d_raw(1025*(array_num-1)+3:1025*(array_num-1)+1025,:);
            d_empty_pick=d_empty(1025*(array_num-1)+3:1025*(array_num-1)+1025,:);

            %auto-scale 
            d_raw_average=mean([d_raw_pick(:,1),d_raw_pick(:,2),d_raw_pick(:,3),d_raw_pick(:,4),d_raw_pick(:,5)],2);   %average for every data
            d_empty_average=mean([d_empty_pick(:,1),d_empty_pick(:,2),d_empty_pick(:,3),d_empty_pick(:,4),d_empty_pick(:,5)],2);   %average for every data


            X_raw=sort(d_raw_average);
            X_empty=sort(d_empty_average);
 
            Y=[0.5/1023 : 1/1023 : (1023-0.5)/1023]';
            
            cla(app.UIAxes3_5);
            plot(app.UIAxes3_5,X_raw,Y,"Color",'r','LineWidth',2);

            cla(app.UIAxes3_6);
            plot(app.UIAxes3_6,X_empty,Y,"Color",'r','LineWidth',2);
               

        end

        % Button pushed function: SettingButton
        function SettingButtonPushed(app, event)
            app.array_visual_frames=app.AverageperEditField.Value;
        end

        % Button pushed function: ShowaveragedreferencegassituationButton
        function ShowaveragedreferencegassituationButtonPushed(app, event)
            
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end

            %%
            app.MatrixselectionDropDown_2.Value=val;

            
            packet_set=ceil(app.AverageperEditField.Value/2);
            ave=app.AverageperEditField.Value;


            app.SelectedsetnumEditField.Value=1;
            app.SetnumtoshowSlider.Value=1;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(1);

            %%
            app.SelectedsetnumEditField_2.Value=1;
            app.SetnumtoshowSlider_2.Value=1;
            app.SelectedtimesEditField_2.Value=t(1);


            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);

            d_chosed_raw=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,1:ave);
            d_chosed=mean(d_chosed_raw,2);

            d_min=min(min(d_chosed(1:1023,:)));
            d_max=max(max(d_chosed));

            app.MINADCvalueEditField.Value=d_min;
            app.MAXADCvalueEditField.Value=d_max;
            colorbar(app.UIAxes,'delete');
            c = colorbar(app.UIAxes); 
            c.Label.String = 'ADC CODE RANGE (/)';
            c.Limits = [d_min d_max];

            if d_max-d_min<=40
                c.Ticks = [d_min: 1 :d_max];
            elseif d_max-d_min <=450
                c.Ticks = [d_min: 10 :d_max];
            else
                c.Ticks = [d_min: 20 :d_max];
            end
            c.FontSize = 12;
            
            colormap(app.UIAxes, "parula"); 
            set(app.UIAxes,'CLim', [d_min d_max]);

            %barp=c.Position;
            c.Location = 'manual';
            c.Position = [0.9423 0.1 0.0113 0.85];
            %display(barp);


            msgbox('The colorbar has been reset','message')

            %d_array=reshape(d_chosed,[32,32]);


            %initialization of selected pixel numbers
            app.signal = zeros(1,1023); %signal pixel number to anlyze
            app.reference = zeros(1,1023); % reference signal pixel number to analyze
            app.signal_lock = zeros(1,1023); %signal pixel number to anlyze
            app.reference_lock = zeros(1,1023); % reference signal pixel number to analyze


            %callbackfunction of patch
            function clickpatch(p,i)
                S=app.Select;
                if S==1
                    disp('patch1');
                    set(p,'edgecolor','r','FaceAlpha',0.4);
                    s=app.signal;
                    s(i)=i;
                    app.signal=s;
                    r=app.reference;
                    r(i)=0;
                    app.reference=r;

                    display(s(i));
                    
                elseif S==2
                    disp('patch2');
                    set(p,'edgecolor','k','FaceAlpha',0.4);
                    r=app.reference;
                    r(i)=i;
                    app.reference=r;
                    s=app.signal;
                    s(i)=0;
                    app.signal=s;

                    display(r(i));
                elseif S==0 
                    disp('patch3')
                    set(p,'edgecolor','none','FaceAlpha',1);
                    s=app.signal;
                    r=app.reference;
                    s(i)=0;
                    r(i)=0;
                    app.signal=s;
                    app.reference=r;

                    display(s(i));
                elseif S==3
                    disp(p.CData);
                    app.EditField.Value = p.CData;
                end
            end


            %unit pixel
            X=[0 0.5 1.5 2 1.5 0.5];
            Y=[(0.5)*3^(0.5) 0 0 (0.5)*3^(0.5) 3^(0.5) 3^(0.5)];

            %draw patches

            %1-960,30*32,15*2*32
            for j=1:15
                    for i=1:32
                        p{i+64*(j-1)}=patch(app.UIAxes,X+3*(j-1),Y+(i-1)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
                    for i=33:64
                        p{i+64*(j-1)}=patch(app.UIAxes,X+1.5+3*(j-1),Y+(64.5-i)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
            end
            %961-992,1*32
            for i=1:32
                p{i+960}=patch(app.UIAxes,X+45,Y+(i-1)*3^(0.5),d_chosed(i+960),'cdatamapping','scaled','edgecolor','none');
                %assign buttondown function
                set(p{i+960},'ButtonDownFcn',@(~,~)clickpatch(p{i+960},i+960));
            end  
            %993-1023,1*31
            for i=1:31
                p{i+992}=patch(app.UIAxes,X+46.5,Y+(32.5-i)*3^(0.5),d_chosed(i+992),'cdatamapping','scaled','edgecolor','none');
                set(p{i+992},'ButtonDownFcn',@(~,~)clickpatch(p{i+992},i+992));
            end
    
            app.patches=p;

msgbox('Data Visualization of this Frame/Set is Completed','message')
        end

        % Button pushed function: 
        % ShowaveragedhighestresponsesituationButton
        function ShowaveragedhighestresponsesituationButtonPushed(app, event)
            val=app.MatrixselectionDropDown.Value;
            if val == "SensorMatrix"
                array_num=1;
            else 
                if val == "SensorMatrix10um5x5"
                array_num=2;
                end
                if val == "SensorMatrix10um4x4"
                array_num=3;
                end
            end
                
            %%%%
            app.MatrixselectionDropDown_2.Value=val;

            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);
            d_chosed=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,:);
            %d_array=reshape(d_chosed,[32,32]);
            
            [max_value, position_max]=max(d_chosed(:));
            [x,y]=ind2sub(size(d_chosed),position_max);
            app.SelectedsetnumEditField.Value=y;
            app.SetnumtoshowSlider.Value=y;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(y);

            %%
            app.SelectedsetnumEditField_2.Value=y;
            app.SetnumtoshowSlider_2.Value=y;
            app.SelectedtimesEditField_2.Value=t(y);


            %%
            
            packet_set=y;
            ave=app.AverageperEditField.Value;
            halfave=ceil(ave/2);

            app.SetnumtoshowSlider.Value=packet_set;
            t=app.t_line;
            app.SelectedtimesEditField.Value=t(packet_set);

            %%
            app.SetnumtoshowSlider_2.Value=packet_set;
            app.SelectedtimesEditField_2.Value=t(packet_set);


            %%display(array_num);
            
            if app.ShowAutoscaledvalueCheckBox.Value==0
                d=app.data_read; 
            else
                d=app.data_read_autoscaled;
            end
            %display(d);
            %display(array_num);
            %display(packet_set);

            d_chosed_raw=d(1025*(array_num-1)+3:1025*(array_num-1)+1026,packet_set-halfave+1:packet_set+halfave);
            d_chosed=mean(d_chosed_raw,2);

            d_min=min(min(d_chosed(1:1023,:)));
            d_max=max(max(d_chosed));

            app.MINADCvalueEditField.Value=d_min;
            app.MAXADCvalueEditField.Value=d_max;
            colorbar(app.UIAxes,'delete');
            c = colorbar(app.UIAxes); 
            c.Label.String = 'ADC CODE RANGE (/)';
            c.Limits = [d_min d_max];

            if d_max-d_min<=40
                c.Ticks = [d_min: 1 :d_max];
            elseif d_max-d_min <=450
                c.Ticks = [d_min: 10 :d_max];
            else
                c.Ticks = [d_min: 20 :d_max];
            end
            c.FontSize = 12;
            
            colormap(app.UIAxes, "parula"); 
            set(app.UIAxes,'CLim', [d_min d_max]);

            %barp=c.Position;
            c.Location = 'manual';
            c.Position = [0.9423 0.1 0.0113 0.85];
            %display(barp);


            msgbox('The colorbar has been reset','message')

            %d_array=reshape(d_chosed,[32,32]);


            %initialization of selected pixel numbers
            app.signal = zeros(1,1023); %signal pixel number to anlyze
            app.reference = zeros(1,1023); % reference signal pixel number to analyze
            app.signal_lock = zeros(1,1023); %signal pixel number to anlyze
            app.reference_lock = zeros(1,1023); % reference signal pixel number to analyze


            %callbackfunction of patch
            function clickpatch(p,i)
                S=app.Select;
                if S==1
                    disp('patch1');
                    set(p,'edgecolor','r','FaceAlpha',0.4);
                    s=app.signal;
                    s(i)=i;
                    app.signal=s;
                    r=app.reference;
                    r(i)=0;
                    app.reference=r;

                    display(s(i));
                    
                elseif S==2
                    disp('patch2');
                    set(p,'edgecolor','k','FaceAlpha',0.4);
                    r=app.reference;
                    r(i)=i;
                    app.reference=r;
                    s=app.signal;
                    s(i)=0;
                    app.signal=s;

                    display(r(i));
                elseif S==0 
                    disp('patch3')
                    set(p,'edgecolor','none','FaceAlpha',1);
                    s=app.signal;
                    r=app.reference;
                    s(i)=0;
                    r(i)=0;
                    app.signal=s;
                    app.reference=r;

                    display(s(i));
                elseif S==3
                    disp(p.CData);
                    app.EditField.Value = p.CData;
                end
            end


            %unit pixel
            X=[0 0.5 1.5 2 1.5 0.5];
            Y=[(0.5)*3^(0.5) 0 0 (0.5)*3^(0.5) 3^(0.5) 3^(0.5)];

            %draw patches

            %1-960,30*32,15*2*32
            for j=1:15
                    for i=1:32
                        p{i+64*(j-1)}=patch(app.UIAxes,X+3*(j-1),Y+(i-1)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
                    for i=33:64
                        p{i+64*(j-1)}=patch(app.UIAxes,X+1.5+3*(j-1),Y+(64.5-i)*3^(0.5),d_chosed(i+64*(j-1)),'cdatamapping','scaled','edgecolor','none');
                        %assign buttondown function
                        set(p{i+64*(j-1)},'ButtonDownFcn',@(~,~)clickpatch(p{i+64*(j-1)},i+64*(j-1)));
                    end
            end
            %961-992,1*32
            for i=1:32
                p{i+960}=patch(app.UIAxes,X+45,Y+(i-1)*3^(0.5),d_chosed(i+960),'cdatamapping','scaled','edgecolor','none');
                %assign buttondown function
                set(p{i+960},'ButtonDownFcn',@(~,~)clickpatch(p{i+960},i+960));
            end  
            %993-1023,1*31
            for i=1:31
                p{i+992}=patch(app.UIAxes,X+46.5,Y+(32.5-i)*3^(0.5),d_chosed(i+992),'cdatamapping','scaled','edgecolor','none');
                set(p{i+992},'ButtonDownFcn',@(~,~)clickpatch(p{i+992},i+992));
            end
    
            app.patches=p;

msgbox('Data Visualization of this Frame/Set is Completed','message')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatapreprocessTab = uitab(app.TabGroup);
            app.DatapreprocessTab.SizeChangedFcn = createCallbackFcn(app, @ShowmatrixButtonPushed, true);
            app.DatapreprocessTab.Title = 'Data pre-process';
            app.GridLayout = uigridlayout(app.DatapreprocessTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Pixel Num (/)')
            ylabel(app.UIAxes, 'ADC code (/)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.CLim = [0 1];
            app.UIAxes.Visible = 'off';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.DataImportPanel = uipanel(app.GridLayout);
            app.DataImportPanel.Title = 'Data Import';
            app.myDatadatfilepathEditFieldLabel = uilabel(app.DataImportPanel);
            app.myDatadatfilepathEditFieldLabel.Text = 'myData.dat file path';
            app.myDatadatfilepathEditField = uieditfield(app.DataImportPanel, 'text');
            app.PrintedchipdataFilefolderSearchandSelectButton = uibutton(app.DataImportPanel, 'push');
            app.PrintedchipdataFilefolderSearchandSelectButton.ButtonPushedFcn = createCallbackFcn(app, @PrintedchipdataFilefolderSearchandSelectButtonPushed, true);
            app.PrintedchipdataFilefolderSearchandSelectButton.Text = {'Printed chip data Filefolder '; 'Search and Select'};
            app.SelectALLsetsandImportdataButton = uibutton(app.DataImportPanel, 'push');
            app.SelectALLsetsandImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectALLsetsandImportdataButtonPushed, true);
            app.SelectALLsetsandImportdataButton.Text = {'Select ALL sets '; 'and Import data'};
            app.EmptychipdataFilefolderSearchandSelectButton = uibutton(app.DataImportPanel, 'push');
            app.EmptychipdataFilefolderSearchandSelectButton.ButtonPushedFcn = createCallbackFcn(app, @EmptychipdataFilefolderSearchandSelectButtonPushed, true);
            app.EmptychipdataFilefolderSearchandSelectButton.Text = {'Empty chip data Filefolder '; 'Search and Select'};
            app.SelectALLsetsandImportdataButton_2 = uibutton(app.DataImportPanel, 'push');
            app.SelectALLsetsandImportdataButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectALLsetsandImportdataButton_2Pushed, true);
            app.SelectALLsetsandImportdataButton_2.Text = {'Select ALL sets '; 'and Import data'};
            app.TotalnumofsetsEditFieldLabel = uilabel(app.DataImportPanel);
            app.TotalnumofsetsEditFieldLabel.Text = 'Total num of sets';
            app.TotalnumofsetsEditField = uieditfield(app.DataImportPanel, 'numeric');
            app.TotalnumofsetsEditField.Editable = 'off';
            app.ColorbarSettingPanel = uipanel(app.GridLayout);
            app.ColorbarSettingPanel.Title = 'Colorbar Setting';
            app.MAXADCvalueEditFieldLabel = uilabel(app.ColorbarSettingPanel);
            app.MAXADCvalueEditFieldLabel.Text = 'MAX ADC value';
            app.MAXADCvalueEditField = uieditfield(app.ColorbarSettingPanel, 'numeric');
            app.ManualsettingButton = uibutton(app.ColorbarSettingPanel, 'push');
            app.ManualsettingButton.ButtonPushedFcn = createCallbackFcn(app, @ManualsettingButtonPushed, true);
            app.ManualsettingButton.Text = 'Manual setting';
            app.AutoselectButton = uibutton(app.ColorbarSettingPanel, 'push');
            app.AutoselectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoselectButtonPushed, true);
            app.AutoselectButton.Text = 'Auto-select';
            app.MINADCvalueEditFieldLabel = uilabel(app.ColorbarSettingPanel);
            app.MINADCvalueEditFieldLabel.Text = 'MIN ADC value';
            app.MINADCvalueEditField = uieditfield(app.ColorbarSettingPanel, 'numeric');
            app.AutoselectrangeLabel = uilabel(app.ColorbarSettingPanel);
            app.AutoselectrangeLabel.Text = {'Auto-select'; 'range'};
            app.AutoselectrangeDropDown = uidropdown(app.ColorbarSettingPanel);
            app.AutoselectrangeDropDown.Items = {'All 3 Matrixs', 'SensorMatrix', 'SensorMatrix10um5x5', 'SensorMatrix10um4x4'};
            app.AutoselectrangeDropDown.Value = 'SensorMatrix';
            app.FindsmallsignalinkButton = uibutton(app.ColorbarSettingPanel, 'push');
            app.FindsmallsignalinkButton.ButtonPushedFcn = createCallbackFcn(app, @FindsmallsignalinkButtonPushed, true);
            app.FindsmallsignalinkButton.Text = 'Find small signal ink';
            app.ResultshownPacketnumberselectionPanel = uipanel(app.GridLayout);
            app.ResultshownPacketnumberselectionPanel.Title = 'Result shown - Packet number selection';
            app.ShowmatrixButton = uibutton(app.ResultshownPacketnumberselectionPanel, 'push');
            app.ShowmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ShowmatrixButtonPushed, true);
            app.ShowmatrixButton.Text = 'Show matrix';
            app.MatrixselectionDropDownLabel = uilabel(app.ResultshownPacketnumberselectionPanel);
            app.MatrixselectionDropDownLabel.Text = 'Matrix selection';
            app.MatrixselectionDropDown = uidropdown(app.ResultshownPacketnumberselectionPanel);
            app.MatrixselectionDropDown.Items = {'SensorMatrix', 'SensorMatrix10um5x5', 'SensorMatrix10um4x4'};
            app.MatrixselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MatrixselectionDropDownValueChanged, true);
            app.MatrixselectionDropDown.Value = 'SensorMatrix';
            app.SetnumtoshowLabel = uilabel(app.ResultshownPacketnumberselectionPanel);
            app.SetnumtoshowLabel.Text = {'  Set num '; ' to show'};
            app.SetnumtoshowSlider = uislider(app.ResultshownPacketnumberselectionPanel);
            app.SetnumtoshowSlider.Limits = [1 10];
            app.SetnumtoshowSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SetnumtoshowSlider.ValueChangedFcn = createCallbackFcn(app, @SetnumtoshowSliderValueChanged, true);
            app.SetnumtoshowSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SetnumtoshowSlider.Value = 1;
            app.SelectedsetnumLabel = uilabel(app.ResultshownPacketnumberselectionPanel);
            app.SelectedsetnumLabel.Text = 'Selected set num (/)';
            app.SelectedsetnumEditField = uieditfield(app.ResultshownPacketnumberselectionPanel, 'numeric');
            app.SelectedsetnumEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedsetnumEditFieldValueChanged, true);
            app.SelectedsetnumEditField.Value = 1;
            app.min1Label = uilabel(app.ResultshownPacketnumberselectionPanel);
            app.min1Label.Text = 'min=1';
            app.maxNumofSetstoreadLabel = uilabel(app.ResultshownPacketnumberselectionPanel);
            app.maxNumofSetstoreadLabel.Text = 'max=Num of Sets to read';
            app.FindHighestResponseSetButton = uibutton(app.ResultshownPacketnumberselectionPanel, 'push');
            app.FindHighestResponseSetButton.ButtonPushedFcn = createCallbackFcn(app, @FindHighestResponseSetButtonPushed, true);
            app.FindHighestResponseSetButton.Text = 'Find Highest Response Set';
            app.sstartdatafromnoVOCsstateneededLabel = uilabel(app.ResultshownPacketnumberselectionPanel);
            app.sstartdatafromnoVOCsstateneededLabel.Text = '2s start data from no VOCs state needed';
            app.ShowAutoscaledvalueCheckBox = uicheckbox(app.ResultshownPacketnumberselectionPanel);
            app.ShowAutoscaledvalueCheckBox.Text = 'Show Auto-scaled value';
            app.SelectedtimesEditFieldLabel = uilabel(app.ResultshownPacketnumberselectionPanel);
            app.SelectedtimesEditFieldLabel.Text = 'Selected time (s)';
            app.SelectedtimesEditField = uieditfield(app.ResultshownPacketnumberselectionPanel, 'numeric');
            app.SelectedtimesEditField.Value = 1;
            app.ShowmatrixButton_2 = uibutton(app.ResultshownPacketnumberselectionPanel, 'push');
            app.ShowmatrixButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowmatrixButton_2Pushed, true);
            app.ShowmatrixButton_2.Text = 'Show matrix';
            app.PixelChosenPanel = uipanel(app.GridLayout);
            app.PixelChosenPanel.Title = 'Pixel Chosen';
            app.SelectSignalPixelsElectrodesButton = uibutton(app.PixelChosenPanel, 'push');
            app.SelectSignalPixelsElectrodesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSignalPixelsElectrodesButtonPushed, true);
            app.SelectSignalPixelsElectrodesButton.Text = 'Select Signal Pixel(s)/Electrode(s)';
            app.SelectReferencePixelsElectrodesButton = uibutton(app.PixelChosenPanel, 'push');
            app.SelectReferencePixelsElectrodesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReferencePixelsElectrodesButtonPushed, true);
            app.SelectReferencePixelsElectrodesButton.Text = 'Select Reference Pixel(s)/Electrode(s)';
            app.CancelselectionButton = uibutton(app.PixelChosenPanel, 'push');
            app.CancelselectionButton.ButtonPushedFcn = createCallbackFcn(app, @CancelselectionButtonPushed, true);
            app.CancelselectionButton.Text = 'Cancel selection';
            app.LockButton = uibutton(app.PixelChosenPanel, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Text = 'Lock';
            app.ShowADCValueButton = uibutton(app.PixelChosenPanel, 'push');
            app.ShowADCValueButton.ButtonPushedFcn = createCallbackFcn(app, @ShowADCValueButtonPushed, true);
            app.ShowADCValueButton.Text = 'Show ADC Value';
            app.CleanallsignalselectionButton = uibutton(app.PixelChosenPanel, 'push');
            app.CleanallsignalselectionButton.ButtonPushedFcn = createCallbackFcn(app, @CleanallsignalselectionButtonPushed, true);
            app.CleanallsignalselectionButton.Text = 'Clean all signal selection';
            app.CleanallreferenceselectionButton = uibutton(app.PixelChosenPanel, 'push');
            app.CleanallreferenceselectionButton.ButtonPushedFcn = createCallbackFcn(app, @CleanallreferenceselectionButtonPushed, true);
            app.CleanallreferenceselectionButton.Text = 'Clean all reference selection';
            app.SelectAllImportDataApplyDefaultSettingButton = uibutton(app.GridLayout, 'push');
            app.SelectAllImportDataApplyDefaultSettingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllImportDataApplyDefaultSettingButtonPushed, true);
            app.SelectAllImportDataApplyDefaultSettingButton.Text = 'Select All & Import Data & Apply Default Setting';
            app.ADCValueforSelectedPixelLabel = uilabel(app.GridLayout);
            app.ADCValueforSelectedPixelLabel.Text = 'ADC Value for Selected Pixel:';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Enable = 'off';
            app.AutoselectpixelPanel = uipanel(app.GridLayout);
            app.AutoselectpixelPanel.Title = 'Auto-select pixel';
            app.AutoselectinkButton = uibutton(app.AutoselectpixelPanel, 'push');
            app.AutoselectinkButton.Enable = 'off';
            app.AutoselectinkButton.Text = 'Auto-select ink';
            app.AutoselectreferenceButton = uibutton(app.AutoselectpixelPanel, 'push');
            app.AutoselectreferenceButton.Enable = 'off';
            app.AutoselectreferenceButton.Text = 'Auto-select reference';
            app.ShowprintedarrayrawarraydataButton = uibutton(app.AutoselectpixelPanel, 'push');
            app.ShowprintedarrayrawarraydataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowprintedarrayrawarraydataButtonPushed, true);
            app.ShowprintedarrayrawarraydataButton.Enable = 'off';
            app.ShowprintedarrayrawarraydataButton.Text = 'Show printed array - raw array data';
            app.ShowautoscaledprintedrawdataButton = uibutton(app.AutoselectpixelPanel, 'push');
            app.ShowautoscaledprintedrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowautoscaledprintedrawdataButtonPushed, true);
            app.ShowautoscaledprintedrawdataButton.Enable = 'off';
            app.ShowautoscaledprintedrawdataButton.Text = 'Show auto-scaled printed - raw data';
            app.ArrayvisualizationtfiltersettiingPanel = uipanel(app.GridLayout);
            app.ArrayvisualizationtfiltersettiingPanel.Title = 'Array visualization t-filter settiing';
            app.SettingButton = uibutton(app.ArrayvisualizationtfiltersettiingPanel, 'push');
            app.SettingButton.ButtonPushedFcn = createCallbackFcn(app, @SettingButtonPushed, true);
            app.SettingButton.Text = 'Setting';
            app.AverageperEditFieldLabel = uilabel(app.ArrayvisualizationtfiltersettiingPanel);
            app.AverageperEditFieldLabel.Text = 'Average per';
            app.AverageperEditField = uieditfield(app.ArrayvisualizationtfiltersettiingPanel, 'numeric');
            app.AverageperEditField.Value = 10;
            app.framesLabel = uilabel(app.ArrayvisualizationtfiltersettiingPanel);
            app.framesLabel.Text = 'frames';
            app.ShowaveragedreferencegassituationButton = uibutton(app.ArrayvisualizationtfiltersettiingPanel, 'push');
            app.ShowaveragedreferencegassituationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowaveragedreferencegassituationButtonPushed, true);
            app.ShowaveragedreferencegassituationButton.Text = 'Show averaged reference gas situation';
            app.ShowaveragedhighestresponsesituationButton = uibutton(app.ArrayvisualizationtfiltersettiingPanel, 'push');
            app.ShowaveragedhighestresponsesituationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowaveragedhighestresponsesituationButtonPushed, true);
            app.ShowaveragedhighestresponsesituationButton.Text = 'Show averaged highest response situation';
            app.ShowaveragedprintedemptychipwithrefgasButton = uibutton(app.ArrayvisualizationtfiltersettiingPanel, 'push');
            app.ShowaveragedprintedemptychipwithrefgasButton.Enable = 'off';
            app.ShowaveragedprintedemptychipwithrefgasButton.Text = {'Show averaged printed-empty chip '; 'with ref gas'};
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GridLayout2 = uigridlayout(app.PlotTab);
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Measured signal/reference signal')
            xlabel(app.UIAxes3, 'time (s)')
            ylabel(app.UIAxes3, 'ADC code (/)')
            zlabel(app.UIAxes3, 'Z')
            app.SAVEPLOTRAWDATANOTESButton = uibutton(app.GridLayout2, 'push');
            app.SAVEPLOTRAWDATANOTESButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEPLOTRAWDATANOTESButtonPushed, true);
            app.SAVEPLOTRAWDATANOTESButton.Text = 'SAVE PLOT RAW DATA & NOTES';
            app.EditFieldLabel = uilabel(app.GridLayout2);
            app.EditFieldLabel.Text = {'Data will be stored '; 'in FILEFOLDER:'};
            app.PLOTrawdataButton_2 = uibutton(app.GridLayout2, 'push');
            app.PLOTrawdataButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLOTrawdataButton_2Pushed, true);
            app.PLOTrawdataButton_2.Text = {'PLOT'; 'raw data'};
            app.DatawillbestoredinFILEFOLDEREditField = uieditfield(app.GridLayout2, 'text');
            app.EditFieldLabel_2 = uilabel(app.GridLayout2);
            app.EditFieldLabel_2.Text = 'Notes:';
            app.Notes = uieditfield(app.GridLayout2, 'text');
            app.ExportsensorselectionimageCheckBox = uicheckbox(app.GridLayout2);
            app.ExportsensorselectionimageCheckBox.Text = 'Export sensor selection image';
            app.ExportsensorselectionimageCheckBox.Value = true;
            app.ExportsensorselectiondatafileCheckBox = uicheckbox(app.GridLayout2);
            app.ExportsensorselectiondatafileCheckBox.Text = 'Export sensor selection data file';
            app.ExportsensorselectiondatafileCheckBox.Value = true;
            app.ShowSIGNALREFERENCECheckBox = uicheckbox(app.GridLayout2);
            app.ShowSIGNALREFERENCECheckBox.Text = 'Show SIGNAL & REFERENCE';
            app.ShowSIGNALREFERENCECheckBox.Value = true;
            app.ShowSIGNALREFERENCECheckBox_2 = uicheckbox(app.GridLayout2);
            app.ShowSIGNALREFERENCECheckBox_2.Text = 'Show SIGNAL - REFERENCE';
            app.ShowSIGNALREFERENCECheckBox_2.Value = true;
            app.TimefilteringperXframesButton = uibutton(app.GridLayout2, 'push');
            app.TimefilteringperXframesButton.ButtonPushedFcn = createCallbackFcn(app, @TimefilteringperXframesButtonPushed, true);
            app.TimefilteringperXframesButton.Text = 'Time filtering per X frames ';
            app.frame_average = uieditfield(app.GridLayout2, 'numeric');
            app.frame_average.Value = 3;
            app.baselinerefEditField = uieditfield(app.GridLayout2, 'numeric');
            app.baselinesignalEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BaselinecorrectionofREFButton = uibutton(app.GridLayout2, 'push');
            app.BaselinecorrectionofREFButton.ButtonPushedFcn = createCallbackFcn(app, @BaselinecorrectionofREFButtonPushed, true);
            app.BaselinecorrectionofREFButton.Text = 'Baseline correction of REF';
            app.ManuallycorrectSIGbaselineButton = uibutton(app.GridLayout2, 'push');
            app.ManuallycorrectSIGbaselineButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallycorrectSIGbaselineButtonPushed, true);
            app.ManuallycorrectSIGbaselineButton.Text = 'Manually correct SIG baseline';
            app.PlottinginonegoButton = uibutton(app.GridLayout2, 'push');
            app.PlottinginonegoButton.ButtonPushedFcn = createCallbackFcn(app, @PlottinginonegoButtonPushed, true);
            app.PlottinginonegoButton.Text = {'Plotting'; ' in one go'};
            app.Plot_autoscaledTab = uitab(app.TabGroup);
            app.Plot_autoscaledTab.Title = 'Plot_autoscaled';
            app.GridLayout2_2 = uigridlayout(app.Plot_autoscaledTab);
            app.UIAxes3_4 = uiaxes(app.GridLayout2_2);
            title(app.UIAxes3_4, 'Measured signal/reference signal')
            xlabel(app.UIAxes3_4, 'time (s)')
            ylabel(app.UIAxes3_4, 'ADC code (/)')
            zlabel(app.UIAxes3_4, 'Z')
            app.SAVEPLOTRAWDATANOTESButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.SAVEPLOTRAWDATANOTESButton_2.ButtonPushedFcn = createCallbackFcn(app, @SAVEPLOTRAWDATANOTESButton_2Pushed, true);
            app.SAVEPLOTRAWDATANOTESButton_2.Text = 'SAVE PLOT RAW DATA & NOTES';
            app.DatawillbestoredinFILEFOLDEREditField_3Label = uilabel(app.GridLayout2_2);
            app.DatawillbestoredinFILEFOLDEREditField_3Label.Text = {'Data will be stored '; 'in FILEFOLDER:'};
            app.DatawillbestoredinFILEFOLDEREditField_3 = uieditfield(app.GridLayout2_2, 'text');
            app.PLOTrawdataButton_3 = uibutton(app.GridLayout2_2, 'push');
            app.PLOTrawdataButton_3.ButtonPushedFcn = createCallbackFcn(app, @PLOTrawdataButton_3Pushed, true);
            app.PLOTrawdataButton_3.Text = {'PLOT'; 'raw data'};
            app.EditFieldLabel_4 = uilabel(app.GridLayout2_2);
            app.EditFieldLabel_4.Text = 'Notes:';
            app.Notes_3 = uieditfield(app.GridLayout2_2, 'text');
            app.ExportsensorselectionimageCheckBox_2 = uicheckbox(app.GridLayout2_2);
            app.ExportsensorselectionimageCheckBox_2.Text = 'Export sensor selection image';
            app.ExportsensorselectionimageCheckBox_2.Value = true;
            app.ExportsensorselectiondatafileCheckBox_2 = uicheckbox(app.GridLayout2_2);
            app.ExportsensorselectiondatafileCheckBox_2.Text = 'Export sensor selection data file';
            app.ExportsensorselectiondatafileCheckBox_2.Value = true;
            app.ShowSIGNALREFERENCECheckBox_3 = uicheckbox(app.GridLayout2_2);
            app.ShowSIGNALREFERENCECheckBox_3.Text = 'Show SIGNAL & REFERENCE';
            app.ShowSIGNALREFERENCECheckBox_3.Value = true;
            app.ShowSIGNALREFERENCECheckBox_4 = uicheckbox(app.GridLayout2_2);
            app.ShowSIGNALREFERENCECheckBox_4.Text = 'Show SIGNAL - REFERENCE';
            app.ShowSIGNALREFERENCECheckBox_4.Value = true;
            app.TimefilteringperXframesButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.TimefilteringperXframesButton_2.ButtonPushedFcn = createCallbackFcn(app, @TimefilteringperXframesButton_2Pushed, true);
            app.TimefilteringperXframesButton_2.Text = 'Time filtering per X frames ';
            app.frame_average_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.frame_average_2.Value = 3;
            app.baselinerefEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.baselinesignalEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.BaselinecorrectionofREFButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.BaselinecorrectionofREFButton_2.ButtonPushedFcn = createCallbackFcn(app, @BaselinecorrectionofREFButton_2Pushed, true);
            app.BaselinecorrectionofREFButton_2.Text = 'Baseline correction of REF';
            app.ManuallycorrectSIGbaselineButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.ManuallycorrectSIGbaselineButton_2.ButtonPushedFcn = createCallbackFcn(app, @ManuallycorrectSIGbaselineButton_2Pushed, true);
            app.ManuallycorrectSIGbaselineButton_2.Text = 'Manually correct SIG baseline';
            app.PlottinginonegoButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.PlottinginonegoButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlottinginonegoButton_2Pushed, true);
            app.PlottinginonegoButton_2.Text = {'Plotting'; ' in one go'};
            app.CombinedplotsTab = uitab(app.TabGroup);
            app.CombinedplotsTab.Title = 'Combined plots';
            app.UIAxes3_2 = uiaxes(app.CombinedplotsTab);
            title(app.UIAxes3_2, 'Measured signal/reference signal')
            xlabel(app.UIAxes3_2, 'time (s)')
            ylabel(app.UIAxes3_2, 'ADC code (/)')
            zlabel(app.UIAxes3_2, 'Z')
            app.PLOTrawdataButton = uibutton(app.CombinedplotsTab, 'push');
            app.PLOTrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTrawdataButtonPushed, true);
            app.PLOTrawdataButton.Text = 'PLOT raw data';
            app.SAVEPLOTIMAGEButton = uibutton(app.CombinedplotsTab, 'push');
            app.SAVEPLOTIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEPLOTIMAGEButtonPushed, true);
            app.SAVEPLOTIMAGEButton.Text = 'SAVE PLOT IMAGE';
            app.DatawillbestoredinFILEFOLDERLabel = uilabel(app.CombinedplotsTab);
            app.DatawillbestoredinFILEFOLDERLabel.Text = {'Data will be stored'; 'in FILEFOLDER:'};
            app.DatawillbestoredinFILEFOLDEREditField_2 = uieditfield(app.CombinedplotsTab, 'text');
            app.EditFieldLabel_3 = uilabel(app.CombinedplotsTab);
            app.EditFieldLabel_3.Text = 'Notes:';
            app.Notes_2 = uieditfield(app.CombinedplotsTab, 'text');
            app.PlottfiltereddataButton = uibutton(app.CombinedplotsTab, 'push');
            app.PlottfiltereddataButton.ButtonPushedFcn = createCallbackFcn(app, @PlottfiltereddataButtonPushed, true);
            app.PlottfiltereddataButton.Text = 'Plot t filtered data';
            app.inYYAXISMODELabel = uilabel(app.CombinedplotsTab);
            app.inYYAXISMODELabel.Text = 'in YYAXIS MODE';
            app.Label = uilabel(app.CombinedplotsTab);
            app.Label.Text = '/';
            app.removereferencebaselineCheckBox = uicheckbox(app.CombinedplotsTab);
            app.removereferencebaselineCheckBox.Text = 'remove reference baseline';
            app.removeADCbaselineCheckBox = uicheckbox(app.CombinedplotsTab);
            app.removeADCbaselineCheckBox.Text = 'remove ΔADC baseline';
            app.CumulativedistributionTab = uitab(app.TabGroup);
            app.CumulativedistributionTab.Title = 'Cumulative distribution';
            app.UIAxes3_5 = uiaxes(app.CumulativedistributionTab);
            title(app.UIAxes3_5, 'Cumulative distribution function plot of RAW data')
            xlabel(app.UIAxes3_5, 'ADC code (/)')
            ylabel(app.UIAxes3_5, 'probability')
            zlabel(app.UIAxes3_5, 'Z')
            app.UIAxes3_6 = uiaxes(app.CumulativedistributionTab);
            title(app.UIAxes3_6, 'Cumulative distribution function plot of ΔADC data')
            xlabel(app.UIAxes3_6, 'ADC code (/)')
            ylabel(app.UIAxes3_6, 'probability')
            zlabel(app.UIAxes3_6, 'Z')
            app.PLOTButton = uibutton(app.CumulativedistributionTab, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.SelectedsetnumEditField_2Label = uilabel(app.CumulativedistributionTab);
            app.SelectedsetnumEditField_2Label.Text = 'Selected set num (/)';
            app.SelectedsetnumEditField_2 = uieditfield(app.CumulativedistributionTab, 'numeric');
            app.SelectedsetnumEditField_2.Value = 1;
            app.SelectedtimesEditField_2Label = uilabel(app.CumulativedistributionTab);
            app.SelectedtimesEditField_2Label.Text = 'Selected time (s)';
            app.SelectedtimesEditField_2 = uieditfield(app.CumulativedistributionTab, 'numeric');
            app.SelectedtimesEditField_2.Value = 1;
            app.ShowCDFplotButton = uibutton(app.CumulativedistributionTab, 'push');
            app.ShowCDFplotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCDFplotButtonPushed, true);
            app.ShowCDFplotButton.Text = 'Show CDF plot';
            app.SetnumtoshowLabel_2 = uilabel(app.CumulativedistributionTab);
            app.SetnumtoshowLabel_2.Text = {'  Set num '; ' to show'};
            app.ShowCDFplotButton_2 = uibutton(app.CumulativedistributionTab, 'push');
            app.ShowCDFplotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowCDFplotButton_2Pushed, true);
            app.ShowCDFplotButton_2.Text = 'Show CDF plot';
            app.MatrixselectionDropDown_2Label = uilabel(app.CumulativedistributionTab);
            app.MatrixselectionDropDown_2Label.Text = 'Matrix selection';
            app.MatrixselectionDropDown_2 = uidropdown(app.CumulativedistributionTab);
            app.MatrixselectionDropDown_2.Items = {'SensorMatrix', 'SensorMatrix10um5x5', 'SensorMatrix10um4x4'};
            app.MatrixselectionDropDown_2.Value = 'SensorMatrix';
            app.FindHighestResponseSetButton_2 = uibutton(app.CumulativedistributionTab, 'push');
            app.FindHighestResponseSetButton_2.ButtonPushedFcn = createCallbackFcn(app, @FindHighestResponseSetButton_2Pushed, true);
            app.FindHighestResponseSetButton_2.Text = 'Find Highest Response Set';
            app.SetnumtoshowSlider_2 = uislider(app.CumulativedistributionTab);
            app.SetnumtoshowSlider_2.Limits = [1 10];
            app.SetnumtoshowSlider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SetnumtoshowSlider_2.ValueChangedFcn = createCallbackFcn(app, @SetnumtoshowSlider_2ValueChanged, true);
            app.SetnumtoshowSlider_2.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SetnumtoshowSlider_2.Value = 1;
            app.SAVEPLOTIMAGEButton_2 = uibutton(app.CumulativedistributionTab, 'push');
            app.SAVEPLOTIMAGEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SAVEPLOTIMAGEButton_2Pushed, true);
            app.SAVEPLOTIMAGEButton_2.Text = 'SAVE PLOT IMAGE';
            app.DatawillbestoredinFILEFOLDERLabel_2 = uilabel(app.CumulativedistributionTab);
            app.DatawillbestoredinFILEFOLDERLabel_2.Text = {'Data will be stored'; 'in FILEFOLDER:'};
            app.DatawillbestoredinFILEFOLDEREditField_4 = uieditfield(app.CumulativedistributionTab, 'text');
            app.EditFieldLabel_5 = uilabel(app.CumulativedistributionTab);
            app.EditFieldLabel_5.Text = 'Notes:';
            app.Notes_4 = uieditfield(app.CumulativedistributionTab, 'text');
            app.min1Label_2 = uilabel(app.CumulativedistributionTab);
            app.min1Label_2.Text = 'min=1';
            app.maxNumofSetstoreadLabel_2 = uilabel(app.CumulativedistributionTab);
            app.maxNumofSetstoreadLabel_2.Text = 'max=Num of Sets to read';
            app.sstartdatafromnoVOCsstateneededLabel_2 = uilabel(app.CumulativedistributionTab);
            app.sstartdatafromnoVOCsstateneededLabel_2.Text = '2s start data from no VOCs state needed';
            app.ShowAutoscaledvalueCheckBox_2 = uicheckbox(app.CumulativedistributionTab);
            app.ShowAutoscaledvalueCheckBox_2.Text = 'Show Auto-scaled value';
            app.ElectrodeanalysisTab = uitab(app.TabGroup);
            app.ElectrodeanalysisTab.Title = 'Electrode analysis';
            app.UIAxes3_7 = uiaxes(app.ElectrodeanalysisTab);
            title(app.UIAxes3_7, 'Empty array')
            xlabel(app.UIAxes3_7, 'ADC code (/)')
            ylabel(app.UIAxes3_7, 'probability')
            zlabel(app.UIAxes3_7, 'Z')
            app.UIAxes3_8 = uiaxes(app.ElectrodeanalysisTab);
            title(app.UIAxes3_8, 'Printed array')
            xlabel(app.UIAxes3_8, 'ADC code (/)')
            ylabel(app.UIAxes3_8, 'probability')
            zlabel(app.UIAxes3_8, 'Z')
            app.PLOTButton_2 = uibutton(app.ElectrodeanalysisTab, 'push');
            app.PLOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLOTButton_2Pushed, true);
            app.PLOTButton_2.Text = 'PLOT';
            app.MatrixselectionDropDown_2Label_2 = uilabel(app.ElectrodeanalysisTab);
            app.MatrixselectionDropDown_2Label_2.Text = 'Matrix selection';
            app.MatrixselectionDropDown_3 = uidropdown(app.ElectrodeanalysisTab);
            app.MatrixselectionDropDown_3.Items = {'SensorMatrix', 'SensorMatrix10um5x5', 'SensorMatrix10um4x4'};
            app.MatrixselectionDropDown_3.Value = 'SensorMatrix';
            app.BothinbasegassituationLabel = uilabel(app.ElectrodeanalysisTab);
            app.BothinbasegassituationLabel.Text = 'Both in base gas situation';
            app.AnalysismanuallyTab = uitab(app.TabGroup);
            app.AnalysismanuallyTab.Title = 'Analysis manually';
            app.UIAxes3_3 = uiaxes(app.AnalysismanuallyTab);
            title(app.UIAxes3_3, 'Measured signal/reference signal')
            xlabel(app.UIAxes3_3, 'time (s)')
            ylabel(app.UIAxes3_3, 'ADC code (/)')
            zlabel(app.UIAxes3_3, 'Z')
            app.AnalyzetfilteredButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.AnalyzetfilteredButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzetfilteredButtonPushed, true);
            app.AnalyzetfilteredButton.Text = 'Analyze t-filtered';
            app.BaselinecorrectionofREFEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.BaselinecorrectionofREFEditFieldLabel.Text = 'Baseline correction of REF';
            app.BaselinecorrectionofREFEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.BaselinecorrectionofSIGEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.BaselinecorrectionofSIGEditFieldLabel.Text = 'Baseline correction of SIG';
            app.BaselinecorrectionofSIGEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.InitialconditionAEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.InitialconditionAEditFieldLabel.Text = 'Initial condition A';
            app.InitialconditionAEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.timeconstantLabel = uilabel(app.AnalysismanuallyTab);
            app.timeconstantLabel.Text = 'time constant τ';
            app.timeconstantEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.samplingtimedeviationt0EditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.samplingtimedeviationt0EditFieldLabel.Text = 'sampling time deviation t0';
            app.samplingtimedeviationt0EditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.AnalyzeRawButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.AnalyzeRawButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeRawButtonPushed, true);
            app.AnalyzeRawButton.Text = 'Analyze Raw';
            app.AnalyzeTogetherButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.AnalyzeTogetherButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeTogetherButtonPushed, true);
            app.AnalyzeTogetherButton.Text = 'Analyze Together';
            app.PickstartpointButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.PickstartpointButton.ButtonPushedFcn = createCallbackFcn(app, @PickstartpointButtonPushed, true);
            app.PickstartpointButton.Text = 'Pick start point';
            app.StarttvalueEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.StarttvalueEditFieldLabel.Text = 'Start t value';
            app.StarttvalueEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.PickendpointButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.PickendpointButton.ButtonPushedFcn = createCallbackFcn(app, @PickendpointButtonPushed, true);
            app.PickendpointButton.Text = 'Pick end point';
            app.EndtvalueEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.EndtvalueEditFieldLabel.Text = 'End t value';
            app.EndtvalueEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.FittingButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.FittingButton.ButtonPushedFcn = createCallbackFcn(app, @FittingButtonPushed, true);
            app.FittingButton.Text = 'Fitting';
            app.cADCAexptEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.cADCAexptEditFieldLabel.Text = 'c(ΔADC)=C+A*exp(-(t-t0)/τ)';
            app.FittingdatatypeButtonGroup = uibuttongroup(app.AnalysismanuallyTab);
            app.FittingdatatypeButtonGroup.Title = 'Fitting data type';
            app.RAWdataButton = uiradiobutton(app.FittingdatatypeButtonGroup);
            app.RAWdataButton.Text = 'RAW data';
            app.RAWdataButton.Value = true;
            app.t_filtereddataButton = uiradiobutton(app.FittingdatatypeButtonGroup);
            app.t_filtereddataButton.Text = 't_filtered data';
            app.RAWdatasmoothedButton = uiradiobutton(app.FittingdatatypeButtonGroup);
            app.RAWdatasmoothedButton.Text = 'RAW data smoothed';
            app.t_filtereddatasmoothedButton = uiradiobutton(app.FittingdatatypeButtonGroup);
            app.t_filtereddatasmoothedButton.Text = 't_filtered data smoothed';
            app.SmoothingButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.SmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothingButtonPushed, true);
            app.SmoothingButton.Text = 'Smoothing';
            app.SmoothedonlyButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.SmoothedonlyButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothedonlyButtonPushed, true);
            app.SmoothedonlyButton.Text = 'Smoothed only';
            app.PickfittingrangeButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.PickfittingrangeButton.ButtonPushedFcn = createCallbackFcn(app, @PickfittingrangeButtonPushed, true);
            app.PickfittingrangeButton.Text = 'Pick fitting range';
            app.orLabel = uilabel(app.AnalysismanuallyTab);
            app.orLabel.Text = 'or';
            app.SmoothingmethodButtonGroup = uibuttongroup(app.AnalysismanuallyTab);
            app.SmoothingmethodButtonGroup.Title = 'Smoothing method';
            app.rlowessButton = uiradiobutton(app.SmoothingmethodButtonGroup);
            app.rlowessButton.Text = 'rlowess';
            app.rloessButton = uiradiobutton(app.SmoothingmethodButtonGroup);
            app.rloessButton.Text = 'rloess';
            app.rloessButton.Value = true;
            app.sgolayButton = uiradiobutton(app.SmoothingmethodButtonGroup);
            app.sgolayButton.Text = 'sgolay';
            app.StarttindexEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.StarttindexEditFieldLabel.Text = 'Start t index';
            app.StarttindexEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.EndtindexEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.EndtindexEditFieldLabel.Text = 'End t index';
            app.EndtindexEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.SaturationvalueCEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.SaturationvalueCEditFieldLabel.Text = 'Saturation value C';
            app.SaturationvalueCEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.resnormEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.resnormEditFieldLabel.Text = 'resnorm';
            app.resnormEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.tfilteredonlyButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.tfilteredonlyButton.ButtonPushedFcn = createCallbackFcn(app, @tfilteredonlyButtonPushed, true);
            app.tfilteredonlyButton.Text = 't-filtered only';
            app.lowpassfilterButton = uibutton(app.AnalysismanuallyTab, 'push');
            app.lowpassfilterButton.ButtonPushedFcn = createCallbackFcn(app, @lowpassfilterButtonPushed, true);
            app.lowpassfilterButton.Text = 'low pass filter';
            app.fpassEditFieldLabel = uilabel(app.AnalysismanuallyTab);
            app.fpassEditFieldLabel.Text = 'fpass';
            app.fpassEditField = uieditfield(app.AnalysismanuallyTab, 'numeric');
            app.fpassEditField.Value = 0.01;
            app.AnalysisautomaticallyTab = uitab(app.TabGroup);
            app.AnalysisautomaticallyTab.Title = 'Analysis automatically';
            app.DatacombinationTab = uitab(app.TabGroup);
            app.DatacombinationTab.Title = 'Data combination';
            app.UITable = uitable(app.DatacombinationTab);
            app.UITable.ColumnName = {'t'; 'Signal-Reference'; 'Signal'; 'Reference'};
            app.UITable.RowName = {};
            app.ProcessedDataImportButton = uibutton(app.DatacombinationTab, 'push');
            app.ProcessedDataImportButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessedDataImportButtonPushed, true);
            app.ProcessedDataImportButton.Text = 'Processed Data Import';
            app.ExtraDataImportButton = uibutton(app.DatacombinationTab, 'push');
            app.ExtraDataImportButton.Text = 'Extra Data Import';
            app.UITable_2 = uitable(app.DatacombinationTab);
            app.UITable_2.ColumnName = {'TITLE 1'};
            app.UITable_2.RowName = {};
            app.CombinedSaveButton = uibutton(app.DatacombinationTab, 'push');
            app.CombinedSaveButton.Text = 'Combined & Save';
            app.DeleteTopLineButton = uibutton(app.DatacombinationTab, 'push');
            app.DeleteTopLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTopLineButtonPushed, true);
            app.DeleteTopLineButton.Text = 'Delete Top Line';
            app.DeleteBottomLineButton = uibutton(app.DatacombinationTab, 'push');
            app.DeleteBottomLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBottomLineButtonPushed, true);
            app.DeleteBottomLineButton.Text = 'Delete Bottom Line';
            app.TurnsideButton = uibutton(app.DatacombinationTab, 'push');
            app.TurnsideButton.ButtonPushedFcn = createCallbackFcn(app, @TurnsideButtonPushed, true);
            app.TurnsideButton.Text = 'Turn side';
            app.AddedValueEditFieldLabel = uilabel(app.DatacombinationTab);
            app.AddedValueEditFieldLabel.Text = 'Added Value';
            app.AddedValueEditField = uieditfield(app.DatacombinationTab, 'numeric');
            app.AddTopLineButton = uibutton(app.DatacombinationTab, 'push');
            app.AddTopLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddTopLineButtonPushed, true);
            app.AddTopLineButton.Text = 'Add Top Line';
            app.AddBottomLineButton = uibutton(app.DatacombinationTab, 'push');
            app.AddBottomLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddBottomLineButtonPushed, true);
            app.AddBottomLineButton.Text = 'Add Bottom Line';
            app.StartfromemptyButton = uibutton(app.DatacombinationTab, 'push');
            app.StartfromemptyButton.ButtonPushedFcn = createCallbackFcn(app, @StartfromemptyButtonPushed, true);
            app.StartfromemptyButton.Text = 'Start from empty';
            app.ChangeTitleButton = uibutton(app.DatacombinationTab, 'push');
            app.ChangeTitleButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeTitleButtonPushed, true);
            app.ChangeTitleButton.Text = 'Change Title';
            app.AddExtraTitleButton = uibutton(app.DatacombinationTab, 'push');
            app.AddExtraTitleButton.Text = 'Add Extra Title';
            app.SelectFolderButton = uibutton(app.DatacombinationTab, 'push');
            app.SelectFolderButton.Text = 'Select Folder';
            app.TitleNameEditFieldLabel = uilabel(app.DatacombinationTab);
            app.TitleNameEditFieldLabel.Text = 'Title Name';
            app.TitleNameEditField = uieditfield(app.DatacombinationTab, 'text');
            app.ExtraTitleEditFieldLabel = uilabel(app.DatacombinationTab);
            app.ExtraTitleEditFieldLabel.Text = 'Extra Title';
            app.ExtraTitleEditField = uieditfield(app.DatacombinationTab, 'text');
            app.DeleteOneTitleFromrightsideButton = uibutton(app.DatacombinationTab, 'push');
            app.DeleteOneTitleFromrightsideButton.Text = 'Delete One Title (From rightside)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myData_process_v2024_8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end